classdef OceanBattery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartSimulationButton           matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        UITable_K_Values_2              matlab.ui.control.Table
        UITable_K_Values                matlab.ui.control.Table
        ComputeKValuesButton            matlab.ui.control.Button
        Image_overview                  matlab.ui.control.Image
        ChargingTab                     matlab.ui.container.Tab
        SubpartChargingDropDown         matlab.ui.control.DropDown
        SubpartDropDownLabel            matlab.ui.control.Label
        ImageChargingLegend             matlab.ui.control.Image
        ImageChargingSmall              matlab.ui.control.Image
        ImageChargingBig                matlab.ui.control.Image
        DischargingTab                  matlab.ui.container.Tab
        SubpartDischargingDropDown      matlab.ui.control.DropDown
        SubpartDropDown_2Label          matlab.ui.control.Label
        ImageDischargingLegend          matlab.ui.control.Image
        ImageDischargingSmall           matlab.ui.control.Image
        ImageDischargingBig             matlab.ui.control.Image
        EfficiencyoftheSystemEditField  matlab.ui.control.NumericEditField
        EfficiencyoftheSystemEditFieldLabel  matlab.ui.control.Label
        EnergyProducedkWhEditField      matlab.ui.control.NumericEditField
        EnergyProducedkWhEditFieldLabel  matlab.ui.control.Label
        EnergySavedkWhEditField         matlab.ui.control.NumericEditField
        EnergySavedkWhEditFieldLabel    matlab.ui.control.Label
        ChargeDischargeDropDown         matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        InformationMenu                 matlab.ui.container.Menu
    end

    properties (Access = private)
                %% Ocean Battery Parameters
        %%  Size parameters
        Lout %[m] Total lenght outer segment rigid reservoir.
        Lin %[m] Total lenght inner segment rigid reservoir.
        D_rigid %[m] Inner diameter of the rigid reservoir.

        %% Accuracy parameters
        Delta_t 

        %% Pump, turbine, motor and generator parameters
        P_electrical %[W] Electrical input power.
        N_motor %[-] Efficiency of the motor.
        N_pump %[-] Efficiency of the pump.
        N_turbine %[-] Efficiency of the turbine.
        N_generator %[-] Efficiency of the generator.
        D_turbine %[m] Diameter of the turbine outlet.

        %% Pipeline parameters
        L_pipeline_charging %[m] Total length of the pipeline during the charging phase.
        L_pipeline_discharging %[m] Total length of the pipeline during the discharging phase.
        D_pipeline %[m] Diameter of the pipeline.
        Roughness_pipeline %[m] Absolute roughness pipeline.

        %% Bypass parameters
        D_bypass %[m] Diamter of the bypass.
        L_bypass %[m] Length of the bypass. 
        Roughness_bypass %[m] Absolute roughness bypass.

        %% External parameters
        Depth %[m] Depth of te system.
        Dens_wat %[kg/m^3] Density of water.
        Visc_wat %[Pa*s] Viscosity of water.
        Dens_air %[kg/m^3] Density of air.
        Visc_air %[Pa*s] Viscosity of air.
        P_atm %[Pa] Atmospheric pressure.
        g%[m/s^2] Gravitational constant.

        %% Umbilical cord parameters
        L_U1 %[m] Length of segment 1 of the umbilical cord.
        L_U2 %[m] Length of segment 2 of the umbilical cord.
        L_U3 %[m] Length of segment 3 of the umbilical cord.
        L_U4 %[m] Length of segment 4 of the umbilical cord.

        p_1 %[-] Dimensionless flow correction coefficient for segment 1 of the umbilical cord.
        p_2 %[-] Dimensionless flow correction coefficient for segment 2 of the umbilical cord.
        p_3 %[-] Dimensionless flow correction coefficient for segment 3 of the umbilical cord.
        p_4 %[-] Dimensionless flow correction coefficient for segment 4 of the umbilical cord.

        D_umbilical %[m] Diameter of the umbilical cord.
        Roughness_umbilical %[m] Absolute roughness umbilical cord. 

        R_bend_umb 
        D_bend_umb 

        %% Initial calculations
        %   It is preferred not to execute calculattions here. If it is possible,
        %   these equations will be moved to another function. DO NOT CHANGE.

        V_rigid_in; %[m^3] volume inner part rigid reservoir
        V_rigid_out %[m^3] volume outer part rigid reservoir
        Capacity_rigid %[m^3] total volume rigid reservoir

        %% Test parameters
        V_wat_rigid_start %[m^3] Starting volume of the rigid reservoir. If the rigid reservoir is full, use "Capacity_rigid".
        V_wat_rigid_end %[m^3] End volume of the rigid reservoir. Set to zero if the rigid reservoir needs to be emtied.
        V_wat_bladder_end %[m^3] End volume of the bladder. Set to zero if the bladder needs to be emptied. 

        %% Other system parameters
        t_open_ball_valve %[s] Time it takes to fully open the ball valve.
        
        %% Paramaters for the calculation of K-values (charging)
        %% 90 degree bend
        Number_90deg_bends_charging %[-] Select the number of 90 degree bends here.
        R_bend_charging %[m] Radius of the bends.
        D_bend_charging %[m] Inner diameter of the bends.
        D_bend_connection_charging %[m] Inner diameter of the entrances and exits of the bends.

        %% Ball valve
        Number_ball_valves_charging %[-] Select the number of ball valves here.
        Angle_ball_valve_charging %[degree] Select the angle of the ball valves here.
        D_ball_valve_charging %[m] Inner diameter of the ball valves.

        %% Union
        Number_unions_charging %[-] Select the number of unions here.
        Union_type_charging %[-] Select the union type here, 1 = threaded // 2 = flanged
        D_union_charging %[m] Inner diameter of the unions.

        %% Pressure sensor
        Number_pressure_sensors_charging %[-] Select the number of pressure sensors here.
        D_pressure_sensor_charging %[m] Inner diameter at the location of the pressure sensor.

        %% Flow meter
        Number_flow_meters_charging %[-] Select the number of flow meters here.
        Flow_meter_type_charging %[-] Select the flow meter type here 1 = electromagnetic // 2 = ultrasonic // 3 = turbine // 4 = paddle wheel
        D_flow_meter_charging %[m] Inner diameter at the location of the flow meters.

        %% Check valve
        Number_check_valves_charging %[-] Select the number of check valves here.
        D_check_valve_charging %[m] Inner diameter of the check valves.

        %% 90-degree elbow
        Number_90deg_elbows_charging %[-] Select the number of 90-degree elbows here.
        D_elbow_90_charging %[m] Inner diameter of the 90-degree elbows.

        %% 45-degree elbow
        Number_45deg_elbows_charging %[-] Select the number of 45-degree elbows here.
        D_elbow_45_charging %[m] Inner diameter of the 45-degree elbows.

        %% Inlets
        Number_inlets_charging %[-] Select the number of inlets here.
        r_inlet_rounded_charging %[m] Radius of the rounded inlet bend.
        Inlet_type_charging %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        D_inlet_charging %[m] Inner diameter of the pipe right after the inlet.

        %% Outlets
        Number_outlets_charging %[-] Select the number of outlets here.
        D_outlet_charging %[m] Inner diameter of the pipe right before the outlet.

        %% Bypass
        Number_bypasses_charging %[-] Select the number of bypasses here.
        Inlet_type_bypass_charging %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        r_inlet_rounded_bypass_charging %[m] Radius of the rounded inlet bend.

        %% Paramaters for the calculation of K-values (discharging)
        %% 90 degree bend
        Number_90deg_bends_discharging %[-] Select the number of 90 degree bends here.
        R_bend_discharging %[m] Radius of the bends.
        D_bend_discharging %[m] Inner diameter of the bends.
        D_bend_connection_discharging %[m] Inner diameter of the entrances and exits of the bends.

        %% Ball valve
        Number_ball_valves_discharging %[-] Select the number of ball valves here.
        Angle_ball_valve_discharging %[degree] Select the angle of the ball valves here.
        D_ball_valve_discharging %[m] Inner diameter of the ball valves.

        %% Union
        Number_unions_discharging %[-] Select the number of unions here.
        Union_type_discharging %[-] Select the union type here, 1 = threaded // 2 = flanged
        D_union_discharging %[m] Inner diameter of the unions.

        %% Pressure sensor
        Number_pressure_sensors_discharging %[-] Select the number of pressure sensors here.
        D_pressure_sensor_discharging %[m] Inner diameter at the location of the pressure sensor.

        %% Flow meter
        Number_flow_meters_discharging %[-] Select the number of flow meters here.
        Flow_meter_type_discharging %[-] Select the flow meter type here 1 = electromagnetic // 2 = ultrasonic // 3 = turbine // 4 = paddle wheel
        D_flow_meter_discharging %[m] Inner diameter at the location of the flow meters.

        %% Check valve
        Number_check_valves_discharging %[-] Select the number of check valves here.
        D_check_valve_discharging %[m] Inner diameter of the check valves.

        %% 90-degree elbow
        Number_90deg_elbows_discharging %[-] Select the number of 90-degree elbows here.
        D_elbow_90_discharging %[m] Inner diameter of the 90-degree elbows.

        %% 45-degree elbow
        Number_45deg_elbows_discharging %[-] Select the number of 45-degree elbows here.
        D_elbow_45_discharging %[m] Inner diameter of the 45-degree elbows.

        %% Inlets
        Number_inlets_discharging %[-] Select the number of inlets here.
        r_inlet_rounded_discharging %[m] Radius of the rounded inlet bend.
        Inlet_type_discharging %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        D_inlet_discharging %[m] Inner diameter of the pipe right after the inlet.

        %% Outlets
        Number_outlets_discharging %[-] Select the number of outlets here.
        D_outlet_discharging %[m] Inner diameter of the pipe right before the outlet.

        %% Bypass
        Number_bypasses_discharging %[-] Select the number of bypasses here.
        Inlet_type_bypass_discharging %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        r_inlet_rounded_bypass_discharging %[m] Radius of the rounded inlet bend.

        %% Calculation Parameters
        P_mechanical_motor
        P_pump
        Interp_steps
        T_empty_charging %[s] Time it takes to empty the rigid reservoir.
        E_elec_in_J %[J] Amount of electrical energy invested in the motor.
        E_elec_in_Wh %[Wh] Amount of electrical energy invested in the motor.
        E_elec_in_kWh %[kWh] Amount of electrical energy invested in the motor.

        %% Model Parameters
        i 
        V_wat_rigid_charging
        Q_pump

        %% Head Parameters
        H_loss_minor_charging
        H_loss_charging_minor
        H_loss_major_charging
        H_loss_major_umbilical_charging
        H_loss_minor_umbilical_charging
        H_loss_total_charging
        H_static_charging
        H_pump

        H_loss_total_discharging
        H_loss_minor_discharging
        H_loss_major_discharging
        H_loss_major_umbilical_discharging
        H_loss_minor_umbilical_discharging
        H_static_discharging
        H_turbine
        Q_turbine
        V_wat_rigid_discharging
        V_wat_bladder
        P_generator
     

        %% GUI state
        table_mode % defines whether the table shows charging or discharging parameters

        %%Structure array for calling functions
        %% GUI Arrays
        OB_GUI_parameters
        K_Values

        N_roundtrip % Description
        T_empty_discharging % Description
        E_elec_out_kWh % Description
        graph_switch % Description

        i_discharging % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %% Ocean Battery Parameters
        %%  Size parameters
        app.Lout = 7.774; %[m] Total lenght outer segment rigid reservoir.
        app.Lin = 2.591; %[m] Total lenght inner segment rigid reservoir.
        app.D_rigid = 0.315; %[m] Inner diameter of the rigid reservoir.

        %% Accuracy parameters
        app.Delta_t = 0.1; 

        %% Pump, turbine, motor and generator parameters
        app.P_electrical = 78.78; %[W] Electrical input power.
        app.N_motor = 1; %[-] Efficiency of the motor.
        app.N_pump = 0.4181; %[-] Efficiency of the pump.
        app.N_turbine = 0.2; %[-] Efficiency of the turbine.
        app.N_generator = 1; %[-] Efficiency of the generator.
        app.D_turbine = 0.034; %[m] Diameter of the turbine outlet.

        %% Pipeline parameters
        app.L_pipeline_charging = 3.77; %[m] Total length of the pipeline during the charging phase.
        app.L_pipeline_discharging = 4.318; %[m] Total length of the pipeline during the discharging phase.
        app.D_pipeline = 0.034; %[m] Diameter of the pipeline.
        app.Roughness_pipeline = 0.0015e-3; %[m] Absolute roughness pipeline.

        %% Bypass parameters
        app.D_bypass = 0.05; %[m] Diamter of the bypass.
        app.L_bypass = 0.579; %[m] Length of the bypass. 
        app.Roughness_bypass = 0.0015e-3; %[m] Absolute roughness bypass.

        %% External parameters
        app.Depth = 5; %[m] Depth of te system.
        app.Dens_wat = 999.15; %[kg/m^3] Density of water.
        app.Visc_wat = 0.001; %[Pa*s] Viscosity of water.
        app.Dens_air = 1.2; %[kg/m^3] Density of air.
        app.Visc_air = 1.865e-5; %[Pa*s] Viscosity of air.
        app.P_atm = 101325; %[Pa] Atmospheric pressure.
        app.g = 9.81;%[m/s^2] Gravitational constant.

        %% Umbilical cord parameters
        app.L_U1 = 10; %[m] Length of segment 1 of the umbilical cord.
        app.L_U2 = 0.6; %[m] Length of segment 2 of the umbilical cord.
        app.L_U3 = 0.4; %[m] Length of segment 3 of the umbilical cord.
        app.L_U4 = 0.4; %[m] Length of segment 4 of the umbilical cord.

        app.p_1  = 1; %[-] Dimensionless flow correction coefficient for segment 1 of the umbilical cord.
        app.p_2  = 1/2; %[-] Dimensionless flow correction coefficient for segment 2 of the umbilical cord.
        app.p_3  = 3/8; %[-] Dimensionless flow correction coefficient for segment 3 of the umbilical cord.
        app.p_4  = 1/8; %[-] Dimensionless flow correction coefficient for segment 4 of the umbilical cord.

        app.D_umbilical = 0.025; %[m] Diameter of the umbilical cord.
        app.Roughness_umbilical = 0.0015e-3; %[m] Absolute roughness umbilical cord. 

        app.R_bend_umb = 0.03;
        app.D_bend_umb = 0.02;

        %% Initial calculations
        %   It is preferred not to execute calculattions here. If it is possible,
        %   these equations will be moved to another function. DO NOT CHANGE.

        app.V_rigid_in = pi*(0.5*app.D_rigid)^2*app.Lin; %[m^3] volume inner part rigid reservoir
        app.V_rigid_out = pi*(0.5*app.D_rigid)^2*app.Lout; %[m^3] volume outer part rigid reservoir
        app.Capacity_rigid = app.V_rigid_in + app.V_rigid_out; %[m^3] total volume rigid reservoir

        %% Test parameters
        app.V_wat_rigid_start = app.Capacity_rigid; %[m^3] Starting volume of the rigid reservoir. If the rigid reservoir is full, use "Capacity_rigid".
        app.V_wat_rigid_end = 0; %[m^3] End volume of the rigid reservoir. Set to zero if the rigid reservoir needs to be emtied.
        app.V_wat_bladder_end = 0; %[m^3] End volume of the bladder. Set to zero if the bladder needs to be emptied. 

        %% Other system parameters
        app.t_open_ball_valve = 12; %[s] Time it takes to fully open the ball valve.

        %% Export OB parameters to structure array
        app.OB_GUI_parameters.Lout = app.Lout; %[m] Total lenght outer segment rigid reservoir.
        app.OB_GUI_parameters.Lin = app.Lin; %[m] Total lenght inner segment rigid reservoir.
        app.OB_GUI_parameters.D_rigid= app.D_rigid; %[m] Inner diameter of the rigid reservoir.
        app.OB_GUI_parameters.Delta_t = app.Delta_t; 
        app.OB_GUI_parameters.P_electrical = app.P_electrical; %[W] Electrical input power.
        app.OB_GUI_parameters.N_motor = app.N_motor; %[-] Efficiency of the motor.
        app.OB_GUI_parameters.N_pump = app.N_pump; %[-] Efficiency of the pump.
        app.OB_GUI_parameters.N_turbine = app.N_turbine; %[-] Efficiency of the turbine.
        app.OB_GUI_parameters.N_generator = app.N_generator; %[-] Efficiency of the generator.
        app.OB_GUI_parameters.D_turbine = app.D_turbine; %[m] Diameter of the turbine outlet.
        app.OB_GUI_parameters.L_pipeline_charging = app.L_pipeline_charging; %[m] Total length of the pipeline during the charging phase.
        app.OB_GUI_parameters.L_pipeline_discharging = app.L_pipeline_discharging; %[m] Total length of the pipeline during the discharging phase.
        app.OB_GUI_parameters.D_pipeline = app.D_pipeline; %[m] Diameter of the pipeline.
        app.OB_GUI_parameters.Roughness_pipeline = app.Roughness_pipeline; %[m] Absolute roughness pipeline.
        app.OB_GUI_parameters.D_bypass = app.D_bypass; %[m] Diamter of the bypass.
        app.OB_GUI_parameters.L_bypass = app.L_bypass; %[m] Length of the bypass. 
        app.OB_GUI_parameters.Roughness_bypass = app.Roughness_bypass; %[m] Absolute roughness bypass.
        app.OB_GUI_parameters.Depth = app.Depth; %[m] Depth of te system.
        app.OB_GUI_parameters.Dens_wat = app.Dens_wat; %[kg/m^3] Density of water.
        app.OB_GUI_parameters.Visc_wat = app.Visc_wat; %[Pa*s] Viscosity of water.
        app.OB_GUI_parameters.Dens_air = app.Dens_air; %[kg/m^3] Density of air.
        app.OB_GUI_parameters.Visc_air = app.Visc_air; %[Pa*s] Viscosity of air.
        app.OB_GUI_parameters.P_atm = app.P_atm; %[Pa] Atmospheric pressure.
        app.OB_GUI_parameters.g = app.g;%[m/s^2] Gravitational constant.
        app.OB_GUI_parameters.L_U1 = app.L_U1; %[m] Length of segment 1 of the umbilical cord.
        app.OB_GUI_parameters.L_U2 = app.L_U2; %[m] Length of segment 2 of the umbilical cord.
        app.OB_GUI_parameters.L_U3 = app.L_U3; %[m] Length of segment 3 of the umbilical cord.
        app.OB_GUI_parameters.L_U4 = app.L_U4; %[m] Length of segment 4 of the umbilical cord.
        app.OB_GUI_parameters.p_1 = app.p_1; %[-] Dimensionless flow correction coefficient for segment 1 of the umbilical cord.
        app.OB_GUI_parameters.p_2 = app.p_2; %[-] Dimensionless flow correction coefficient for segment 2 of the umbilical cord.
        app.OB_GUI_parameters.p_3 = app.p_3; %[-] Dimensionless flow correction coefficient for segment 3 of the umbilical cord.
        app.OB_GUI_parameters.p_4 = app.p_4; %[-] Dimensionless flow correction coefficient for segment 4 of the umbilical cord.
        app.OB_GUI_parameters.D_umbilical = app.D_umbilical; %[m] Diameter of the umbilical cord.
        app.OB_GUI_parameters.Roughness_umbilical = app.Roughness_umbilical; %[m] Absolute roughness umbilical cord. 
        app.OB_GUI_parameters.R_bend_umb = app.R_bend_umb ;
        app.OB_GUI_parameters.D_bend_umb = app.D_bend_umb;
        app.OB_GUI_parameters.V_rigid = app.V_rigid_in; %[m^3] volume inner part rigid reservoir
        app.OB_GUI_parameters.V_rigid_out = app.V_rigid_out; %[m^3] volume outer part rigid reservoir
        app.OB_GUI_parameters.Capacity_rigid = app.Capacity_rigid; %[m^3] total volume rigid reservoir
        app.OB_GUI_parameters.V_wat_rigid_start = app.V_wat_rigid_start; %[m^3] Starting volume of the rigid reservoir. If the rigid reservoir is full, use "Capacity_rigid".
        app.OB_GUI_parameters.V_wat_rigid_end = app.V_wat_rigid_end; %[m^3] End volume of the rigid reservoir. Set to zero if the rigid reservoir needs to be emtied.
        app.OB_GUI_parameters.V_wat_bladder_end = app.V_wat_bladder_end; %[m^3] End volume of the bladder. Set to zero if the bladder needs to be emptied. 
        app.OB_GUI_parameters.t_open_ball_valve = app.t_open_ball_valve; %[s] Time it takes to fully open the ball valve.

        %% Paramaters for the calculation of K-values (charging)
        %   In this script the parameters for the minor losses of the charging phase
        %   can be set.

        %% 90 degree bend
        app.Number_90deg_bends_charging = 8; %[-] Select the number of 90 degree bends here.
        app.R_bend_charging = 0.048; %[m] Radius of the bends.
        app.D_bend_charging = 0.034; %[m] Inner diameter of the bends.
        app.D_bend_connection_charging = 0.038;%[m] Inner diameter of the entrances and exits of the bends.

        %% Ball valve
        app.Number_ball_valves_charging = 0; %[-] Select the number of ball valves here.
        app.Angle_ball_valve_charging = 0; %[degree] Select the angle of the ball valves here.
        app.D_ball_valve_charging = 0.034; %[m] Inner diameter of the ball valves.

        %% Union
        app.Number_unions_charging = 2; %[-] Select the number of unions here.
        app.Union_type_charging = 1; %[-] Select the union type here, 1 = threaded // 2 = flanged
        app.D_union_charging = 0.034; %[m] Inner diameter of the unions.

        %% Pressure sensor
        app.Number_pressure_sensors_charging = 3; %[-] Select the number of pressure sensors here.
        app.D_pressure_sensor_charging = 0.034; %[m] Inner diameter at the location of the pressure sensor.

        %% Flow meter
        app.Number_flow_meters_charging = 1; %[-] Select the number of flow meters here.
        app.Flow_meter_type_charging = 4; %[-] Select the flow meter type here 1 = electromagnetic // 2 = ultrasonic // 3 = turbine // 4 = paddle wheel
        app.D_flow_meter_charging = 0.034; %[m] Inner diameter at the location of the flow meters.

        %% Check valve
        app.Number_check_valves_charging = 1; %[-] Select the number of check valves here.
        app.D_check_valve_charging = 0.034; %[m] Inner diameter of the check valves.

        %% 90-degree elbow
        app.Number_90deg_elbows_charging = 0; %[-] Select the number of 90-degree elbows here.
        app.D_elbow_90_charging = 0.034; %[m] Inner diameter of the 90-degree elbows.

        %% 45-degree elbow
        app.Number_45deg_elbows_charging = 3; %[-] Select the number of 45-degree elbows here.
        app.D_elbow_45_charging = 0.034; %[m] Inner diameter of the 45-degree elbows.

        %% Inlets
        app.Number_inlets_charging = 1; %[-] Select the number of inlets here.
        app.r_inlet_rounded_charging = .0001; %[m] Radius of the rounded inlet bend.
        app.Inlet_type_charging = 3; %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        app.D_inlet_charging = 0.034; %[m] Inner diameter of the pipe right after the inlet.

        %% Outlets
        app.Number_outlets_charging = 1; %[-] Select the number of outlets here.
        app.D_outlet_charging = 0.034; %[m] Inner diameter of the pipe right before the outlet.

        %% Bypass
        %   the bypass is mentioned seperate and not as a set of inlets and
        %   outlets because it has a different diamter and a different amount of flow
        %   passes though it. It also provides a better overview of the system.

        app.Number_bypasses_charging = 1; %[-] Select the number of bypasses here.
        app.D_bypass = 0.05; %[m] Inner diameter of the bypass.
        app.Inlet_type_bypass_charging = 3; %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        app.r_inlet_rounded_bypass_charging = .0001; %[m] Radius of the rounded inlet bend.

        %% Export charging variables to structure array
        app.OB_GUI_parameters.Number_90deg_bends_charging=app.Number_90deg_bends_charging;
        app.OB_GUI_parameters.R_bend_charging=app.R_bend_charging;
        app.OB_GUI_parameters.D_bend_charging=app.D_bend_charging;
        app.OB_GUI_parameters.D_bend_connection_charging=app.D_bend_connection_charging;
        app.OB_GUI_parameters.Number_ball_valves_charging=app.Number_ball_valves_charging;
        app.OB_GUI_parameters.Angle_ball_valve_charging=app.Angle_ball_valve_charging;
        app.OB_GUI_parameters.D_ball_valve_charging=app.D_ball_valve_charging;
        app.OB_GUI_parameters.Number_unions_charging=app.Number_unions_charging;
        app.OB_GUI_parameters.Union_type_charging=app.Union_type_charging;
        app.OB_GUI_parameters.D_union_charging=app.D_union_charging;
        app.OB_GUI_parameters.Number_pressure_sensors_charging=app.Number_pressure_sensors_charging;
        app.OB_GUI_parameters.D_pressure_sensor_charging=app.D_pressure_sensor_charging;
        app.OB_GUI_parameters.Number_flow_meters_charging=app.Number_flow_meters_charging;
        app.OB_GUI_parameters.Flow_meter_type_charging=app.Flow_meter_type_charging;
        app.OB_GUI_parameters.D_flow_meter_charging=app.D_flow_meter_charging;
        app.OB_GUI_parameters.Number_check_valves_charging=app.Number_check_valves_charging;
        app.OB_GUI_parameters.D_check_valve_charging=app.D_check_valve_charging;
        app.OB_GUI_parameters.Number_90deg_elbows_charging=app.Number_90deg_elbows_charging;
        app.OB_GUI_parameters.D_elbow_90_charging=app.D_elbow_90_charging;
        app.OB_GUI_parameters.Number_45deg_elbows_charging=app.Number_45deg_elbows_charging;
        app.OB_GUI_parameters.Number_45deg_elbows_charging=app.Number_45deg_elbows_charging;
        app.OB_GUI_parameters.D_elbow_45_charging=app.D_elbow_45_charging;
        app.OB_GUI_parameters.Number_inlets_charging=app.Number_inlets_charging;
        app.OB_GUI_parameters.r_inlet_rounded_charging=app.r_inlet_rounded_charging;
        app.OB_GUI_parameters.Inlet_type_charging=app.Inlet_type_charging;
        app.OB_GUI_parameters.D_inlet_charging=app.D_inlet_charging;
        app.OB_GUI_parameters.Number_outlets_charging=app.Number_outlets_charging;
        app.OB_GUI_parameters.Number_outlets_charging=app.Number_outlets_charging;
        app.OB_GUI_parameters.D_outlet_charging=app.D_outlet_charging;
        app.OB_GUI_parameters.Number_bypasses_charging=app.Number_bypasses_charging;
        app.OB_GUI_parameters.D_bypass=app.D_bypass;
        app.OB_GUI_parameters.Inlet_type_bypass_charging=app.Inlet_type_bypass_charging;
        app.OB_GUI_parameters.r_inlet_rounded_bypass_charging=app.r_inlet_rounded_bypass_charging;
       
        %% Paramaters for the calculation of K-values (discharging)
        %   In this script the parameters for the minor losses of the charging phase
        %   can be set.

        %% 90 degree bend
        app.Number_90deg_bends_discharging = 12; %[-] Select the number of 90 degree bends here.
        app.R_bend_discharging = 0.048; %[m] Radius of the bends.
        app.D_bend_discharging = 0.034; %[m] Inner diameter of the bends.
        app.D_bend_connection_discharging = 0.038;%[m] Inner diameter of the entrances and exits of the bends.

        %% Ball valve
        app.Number_ball_valves_discharging = 1; %[-] Select the number of ball valves here.
        app.Angle_ball_valve_discharging = 0; %[degree] Select the angle of the ball valves here.
        app.D_ball_valve_discharging = 0.034; %[m] Inner diameter of the ball valves.

        %% Union
        app.Number_unions_discharging = 2; %[-] Select the number of unions here.
        app.Union_type_discharging = 1; %[-] Select the union type here, 1 = threaded // 2 = flanged
        app.D_union_discharging = 0.034; %[m] Inner diameter of the unions.

        %% Pressure sensor
        app.Number_pressure_sensors_discharging = 3; %[-] Select the number of pressure sensors here.
        app.D_pressure_sensor_discharging = 0.034; %[m] Inner diameter at the location of the pressure sensor.

        %% Flow meter
        app.Number_flow_meters_discharging = 1; %[-] Select the number of flow meters here.
        app.Flow_meter_type_discharging = 4; %[-] Select the flow meter type here 1 = electromagnetic // 2 = ultrasonic // 3 = turbine // 4 = paddle wheel
        app.D_flow_meter_discharging = 0.034; %[m] Inner diameter at the location of the flow meters.

        %% Check valve
        app.Number_check_valves_discharging = 0; %[-] Select the number of check valves here.
        app.D_check_valve_discharging = 0.034; %[m] Inner diameter of the check valves.

        %% 90-degree elbow
        app.Number_90deg_elbows_discharging = 2; %[-] Select the number of 90-degree elbows here.
        app.D_elbow_90_discharging = 0.034; %[m] Inner diameter of the 90-degree elbows.

        %% 45-degree elbow
        app.Number_45deg_elbows_discharging = 0; %[-] Select the number of 45-degree elbows here.
        app.D_elbow_45_discharging = 0.034; %[m] Inner diameter of the 45-degree elbows.

        %% Inlets
        app.Number_inlets_discharging = 1; %[-] Select the number of inlets here.
        app.r_inlet_rounded_discharging = .0001; %[m] Radius of the rounded inlet bend.
        app.Inlet_type_discharging = 3; %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        app.D_inlet_discharging = 0.034; %[m] Inner diameter of the pipe right after the inlet.

        %% Outlets
        app.Number_outlets_discharging = 1; %[-] Select the number of outlets here.
        app.D_outlet_discharging = 0.034; %[m] Inner diameter of the pipe right before the outlet.

        %% Bypass
        %   the bypass is mentioned seperate and not as a set of inlets and
        %   outlets because it has a different diamter and a different amount of flow
        %   passes though it. It also provides a better overview of the system.

        app.Number_bypasses_discharging = 1; %[-] Select the number of bypasses here.
        app.D_bypass = 0.05; %[m] Inner diameter of the bypass.
        app.Inlet_type_bypass_discharging = 3; %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
        app.r_inlet_rounded_bypass_discharging = .0001; %[m] Radius of the rounded inlet bend.

        %% Export discharging variables to structure array

        app.OB_GUI_parameters.Number_90deg_bends_discharging=app.Number_90deg_bends_discharging;
        app.OB_GUI_parameters.R_bend_discharging=app.R_bend_discharging;
        app.OB_GUI_parameters.D_bend_discharging=app.D_bend_discharging;
        app.OB_GUI_parameters.D_bend_connection_discharging=app.D_bend_connection_discharging;
        app.OB_GUI_parameters.Number_ball_valves_discharging=app.Number_ball_valves_discharging;
        app.OB_GUI_parameters.Angle_ball_valve_discharging=app.Angle_ball_valve_discharging;
        app.OB_GUI_parameters.D_ball_valve_discharging=app.D_ball_valve_discharging;
        app.OB_GUI_parameters.Number_unions_discharging=app.Number_unions_discharging;
        app.OB_GUI_parameters.Union_type_discharging=app.Union_type_discharging;
        app.OB_GUI_parameters.D_union_discharging=app.D_union_discharging;
        app.OB_GUI_parameters.Number_pressure_sensors_discharging=app.Number_pressure_sensors_discharging;
        app.OB_GUI_parameters.D_pressure_sensor_discharging=app.D_pressure_sensor_discharging;
        app.OB_GUI_parameters.Number_flow_meters_discharging=app.Number_flow_meters_discharging;
        app.OB_GUI_parameters.Flow_meter_type_discharging=app.Flow_meter_type_discharging;
        app.OB_GUI_parameters.D_flow_meter_discharging=app.D_flow_meter_discharging;
        app.OB_GUI_parameters.Number_check_valves_discharging=app.Number_check_valves_discharging;
        app.OB_GUI_parameters.D_check_valve_discharging=app.D_check_valve_discharging;
        app.OB_GUI_parameters.Number_90deg_elbows_discharging=app.Number_90deg_elbows_discharging;
        app.OB_GUI_parameters.D_elbow_90_discharging=app.D_elbow_90_discharging;
        app.OB_GUI_parameters.Number_45deg_elbows_discharging=app.Number_45deg_elbows_discharging;
        app.OB_GUI_parameters.Number_45deg_elbows_discharging=app.Number_45deg_elbows_discharging;
        app.OB_GUI_parameters.D_elbow_45_discharging=app.D_elbow_45_discharging;
        app.OB_GUI_parameters.Number_inlets_discharging=app.Number_inlets_discharging;
        app.OB_GUI_parameters.r_inlet_rounded_discharging=app.r_inlet_rounded_discharging;
        app.OB_GUI_parameters.Inlet_type_discharging=app.Inlet_type_discharging;
        app.OB_GUI_parameters.D_inlet_discharging=app.D_inlet_discharging;
        app.OB_GUI_parameters.Number_outlets_discharging=app.Number_outlets_discharging;
        app.OB_GUI_parameters.Number_outlets_discharging=app.Number_outlets_discharging;
        app.OB_GUI_parameters.D_outlet_discharging=app.D_outlet_discharging;
        app.OB_GUI_parameters.Number_bypasses_discharging=app.Number_bypasses_discharging;
        app.OB_GUI_parameters.D_bypass=app.D_bypass;
        app.OB_GUI_parameters.Inlet_type_bypass_discharging=app.Inlet_type_bypass_discharging;
        app.OB_GUI_parameters.r_inlet_rounded_bypass_discharging=app.r_inlet_rounded_bypass_discharging;

        %% Loading images
        app.Image_overview.ImageSource="system.png";
        app.Image_overview.ScaleMethod='stretch';

        %% Variables to table 1
        app.table_mode="main_parameters";
                app.UITable.RowName=["P_electrical", "N_pump", "N_turbine",...
                    "Depth", "Lout", "Lin", "D_rigid", "L_pipeline_discharging",...
                    "D_pipeline", "L_pipeline_charging"];
                app.UITable.Data={app.OB_GUI_parameters.P_electrical; app.OB_GUI_parameters.N_pump; app.OB_GUI_parameters.N_turbine;...
                    app.OB_GUI_parameters.Depth; app.OB_GUI_parameters.Lout; app.OB_GUI_parameters.Lin; app.OB_GUI_parameters.D_rigid; app.OB_GUI_parameters.L_pipeline_discharging;...
                    app.OB_GUI_parameters.D_pipeline; app.OB_GUI_parameters.L_pipeline_charging;};

        %% Initialize graphs

        % Plot results

            app.graph_switch=1;
            
            % Plot 1: head losses
            cla(app.UIAxes)
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.H_loss_total_charging);
            plot(app.UIAxes,app.H_loss_minor_charging);
            plot(app.UIAxes,app.H_loss_major_charging);
            plot(app.UIAxes,app.H_loss_major_umbilical_charging);
            plot(app.UIAxes,app.H_loss_minor_umbilical_charging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes,'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical');
            title(app.UIAxes,'Head loss (Charging)');
    
            % Plot 2: pump head
            cla(app.UIAxes_2)
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,app.H_static_charging);
            plot(app.UIAxes_2,app.H_pump);
            plot(app.UIAxes_2,app.H_loss_total_charging);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2,'Static head','Pump head','Total head loss')
            title(app.UIAxes_2,'Pump head (Charging)')
    
            % Plot 3 Flow through the pump
            cla(app.UIAxes_3)
            plot(app.UIAxes_3,app.Q_pump);
            xlabel(app.UIAxes_3,'Time [ds]');
            ylabel(app.UIAxes_3,'Volumetric flowrate [m^3/s]')
            title(app.UIAxes_3,'Flow through the pump(Charging)')
    
            % Plot 4: volume of the fluid present in the rigid reservoir
            cla(app.UIAxes_4)
            plot(app.UIAxes_4,app.V_wat_rigid_charging);
            xlabel(app.UIAxes_4,'Time [ds]');
            ylabel(app.UIAxes_4,'Volume [m^3]')
            title(app.UIAxes_4,'Volume of fluid present in the rigid reservoir(Charging)')
            legend(app.UIAxes_4, 'Rigid reservoir')

        %% Show information in text area

        app.TextArea.Value="A general overview of the system is given. The following sub-parts are indicated in the system: the rigid reservoir, the flexible bladder, the pumping system, and the turbine system. Based on the entered parameters, the K-values throughout the system can be calculated.";
                

        end

        % Value changed function: ChargeDischargeDropDown
        function ChargeDischargeDropDownValueChanged(app, event)
            value = app.ChargeDischargeDropDown.Value;
            if value=="Charging Parameters"
                app.table_mode="charging_parameters"; 
            app.UITable.RowName=["Number_90deg_bends","R_bend","D_bend","D_bend_connection",...
                "Number_ball_valves","Angle_ball_valve","D_ball_valve","Number_unions",...
                "Union_type","D_union","Number_pressure_sensors","D_pressure_sensor",...
                "Number_flow_meters","Flow_meter_type","D_flow_meter","Number_check_valves",...
                "D_check_valve","Number_90deg_elbows","D_elbow_90","Number_45deg_elbows",...
                "D_elbow_45","Number_inlets","r_inlet_rounded","Inlet_type","D_inlet_discharging"...
                "Number_outlets","D_outlet","Number_bypasses","D_bypass",...
                "Inlet_type_bypass","r_inlet_rounded_bypass",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_charging;app.OB_GUI_parameters.R_bend_charging;app.OB_GUI_parameters.D_bend_charging;app.OB_GUI_parameters.D_bend_connection_charging;...
                app.OB_GUI_parameters.Number_ball_valves_charging;app.OB_GUI_parameters.Angle_ball_valve_charging;app.OB_GUI_parameters.D_ball_valve_charging;app.OB_GUI_parameters.Number_unions_charging;...
                app.OB_GUI_parameters.Union_type_charging;app.OB_GUI_parameters.D_union_charging;app.OB_GUI_parameters.Number_pressure_sensors_charging;app.OB_GUI_parameters.D_pressure_sensor_charging;...
                app.OB_GUI_parameters.Number_flow_meters_charging;app.OB_GUI_parameters.Flow_meter_type_charging;app.OB_GUI_parameters.D_flow_meter_charging;app.OB_GUI_parameters.Number_check_valves_charging;...
                app.OB_GUI_parameters.D_check_valve_charging;app.OB_GUI_parameters.Number_90deg_elbows_charging;app.OB_GUI_parameters.D_elbow_90_charging;app.OB_GUI_parameters.Number_45deg_elbows_charging;...
                app.OB_GUI_parameters.D_elbow_45_charging;app.OB_GUI_parameters.Number_inlets_charging;app.OB_GUI_parameters.r_inlet_rounded_charging;app.OB_GUI_parameters.Inlet_type_charging;app.OB_GUI_parameters.D_inlet_charging;...
                app.OB_GUI_parameters.Number_outlets_charging;app.OB_GUI_parameters.D_outlet_charging;app.OB_GUI_parameters.Number_bypasses_charging;app.OB_GUI_parameters.D_bypass;...
                app.OB_GUI_parameters.Inlet_type_bypass_charging;app.OB_GUI_parameters.r_inlet_rounded_bypass_charging;};


            elseif value=="Discharging Parameters"
                app.table_mode="discharging_parameters";
            app.UITable.RowName=["Number_90deg_bends","R_bend","D_bend","D_bend_connection",...
                "Number_ball_valves","Angle_ball_valve","D_ball_valve","Number_unions",...
                "Union_type","D_union","Number_pressure_sensors","D_pressure_sensor",...
                "Number_flow_meters","Flow_meter_type","D_flow_meter","Number_check_valves",...
                "D_check_valve","Number_90deg_elbows","D_elbow_90","Number_45deg_elbows",...
                "D_elbow_45","Number_inlets","r_inlet_rounded","Inlet_type","D_inlet_discharging"...
                "Number_outlets","D_outlet","Number_bypasses","D_bypass",...
                "Inlet_type_bypass","r_inlet_rounded_bypass",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_discharging;app.OB_GUI_parameters.R_bend_discharging;app.OB_GUI_parameters.D_bend_discharging;app.OB_GUI_parameters.D_bend_connection_discharging;...
                app.OB_GUI_parameters.Number_ball_valves_discharging;app.OB_GUI_parameters.Angle_ball_valve_discharging;app.OB_GUI_parameters.D_ball_valve_discharging;app.OB_GUI_parameters.Number_unions_discharging;...
                app.OB_GUI_parameters.Union_type_discharging;app.OB_GUI_parameters.D_union_discharging;app.OB_GUI_parameters.Number_pressure_sensors_discharging;app.OB_GUI_parameters.D_pressure_sensor_discharging;...
                app.OB_GUI_parameters.Number_flow_meters_discharging;app.Flow_meter_type_discharging;app.OB_GUI_parameters.D_flow_meter_discharging;app.OB_GUI_parameters.Number_check_valves_discharging;...
                app.OB_GUI_parameters.D_check_valve_discharging;app.OB_GUI_parameters.Number_90deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_90_discharging;app.OB_GUI_parameters.Number_45deg_elbows_discharging;...
                app.OB_GUI_parameters.D_elbow_45_discharging;app.OB_GUI_parameters.Number_inlets_discharging;app.OB_GUI_parameters.r_inlet_rounded_discharging;app.OB_GUI_parameters.Inlet_type_discharging;app.OB_GUI_parameters.D_inlet_discharging;...
                app.OB_GUI_parameters.Number_outlets_discharging;app.OB_GUI_parameters.D_outlet_discharging;app.OB_GUI_parameters.Number_bypasses_discharging;app.OB_GUI_parameters.D_bypass;...
                app.OB_GUI_parameters.Inlet_type_bypass_discharging;app.OB_GUI_parameters.r_inlet_rounded_bypass_discharging;};
            
            elseif value=="Main Parameters"
                app.table_mode="main_parameters";
                app.UITable.RowName=["P_electrical", "N_pump", "N_turbine",...
                    "Depth", "Lout", "Lin", "D_rigid", "L_pipeline_discharging",...
                    "D_pipeline", "L_pipeline_charging"];
                app.UITable.Data={app.OB_GUI_parameters.P_electrical; app.OB_GUI_parameters.N_pump; app.OB_GUI_parameters.N_turbine;...
                    app.OB_GUI_parameters.Depth; app.OB_GUI_parameters.Lout; app.OB_GUI_parameters.Lin; app.OB_GUI_parameters.D_rigid; app.OB_GUI_parameters.L_pipeline_discharging;...
                    app.OB_GUI_parameters.D_pipeline; app.OB_GUI_parameters.L_pipeline_charging;};
            end
             
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if app.table_mode=="charging_parameters"
                if indices(1)==1
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_90deg_bends_charging = newData;
                    end
                elseif indices(1) == 2
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.R_bend_charging = newData;
                    end
                elseif indices(1) == 3
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_bend_charging = newData;
                    end
                elseif indices(1) == 4
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_bend_connection_charging = newData;
                    end
                elseif indices(1) == 5
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_ball_valves_charging = newData;
                    end
                elseif indices(1) == 6
                    if newData<0 || newData >90
                        uialert(app.UIFigure,'Please use a value between 0 and 90 degrees','Input Error');
                    else
                        app.OB_GUI_parameters.Angle_ball_valve_charging = newData;
                    end
                elseif indices(1) == 7
                    if newData<0 || newData >90
                        uialert(app.UIFigure,'Please use a value between 0 and 90 degrees','Input Error');
                    else
                        app.OB_GUI_parameters.D_ball_valve_charging = newData;
                    end
                elseif indices(1) == 8
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_unions_charging = newData;
                    end
                elseif indices(1) == 9
                    if newData ~= 1 && newData ~= 2
                        uialert(app.UIFigure,'Please use type 1 = threaded or type 2 = flanged','Input Error');
                    else
                        app.OB_GUI_parameters.Union_type_charging = newData;
                    end
                elseif indices(1) == 10
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_union_charging = newData;
                    end
                elseif indices(1) == 11
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_pressure_sensors_charging = newData;
                    end
                elseif indices(1) == 12
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_pressure_sensor_charging = newData;
                    end
                elseif indices(1) == 13
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_flow_meters_charging = newData;
                    end
                elseif indices(1) == 14
                    if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                        uialert(app.UIFigure,'Please use the flow meter type here 1 = electromagnetic, 2 = ultrasonic, 3 = turbine, 4 = paddle wheel','Input Error');
                    else
                        app.OB_GUI_parameters.Flow_meter_type_charging = newData;
                    end
                elseif indices(1) == 15
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_flow_meter_charging = newData;
                    end
                elseif indices(1) == 16
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_check_valves_charging = newData;
                    end
                elseif indices(1) == 17
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_check_valve_charging = newData;
                    end
                elseif indices(1) == 18
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_90deg_elbows_charging = newData;
                    end
                elseif indices(1) == 19
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_elbow_90_charging = newData;
                    end
                elseif indices(1) == 20
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_45deg_elbows_charging = newData;
                    end
                elseif indices(1) == 21
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_elbow_45_charging = newData;
                    end
                elseif indices(1) == 22
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_inlets_charging = newData;
                    end
                elseif indices(1) == 23
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.r_inlet_rounded_charging = newData;
                    end
                elseif indices(1) == 24
                    if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                        uialert(app.UIFigure,'Please use the inlet type here, 1 = square edge, 2 = rounded erge, 3 = extended edge, 4 = chamfered edge','Input Error');
                    else
                        app.OB_GUI_parameters.Inlet_type_charging = newData;
                    end
                elseif indices(1) == 25
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_inlet_charging = newData;
                    end
                elseif indices(1) == 26
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_outlets_charging = newData;
                    end
                elseif indices(1) == 27
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_outlet_charging = newData;
                    end
                elseif indices(1) == 28
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_bypasses_charging = newData;
                    end
                elseif indices(1) == 29
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_bypass = newData;
                    end
                elseif indices(1) == 30
                    if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                        uialert(app.UIFigure,'Please use the inlet type here, 1 = square edge, 2 = rounded erge, 3 = extended edge, 4 = chamfered edge','Input Error');
                    else
                        app.OB_GUI_parameters.Inlet_type_bypass_charging = newData;
                    end
                elseif indices(1) == 31
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.r_inlet_rounded_bypass_charging = newData;
                    end
                end

                app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_charging;app.OB_GUI_parameters.R_bend_charging;app.OB_GUI_parameters.D_bend_charging;app.OB_GUI_parameters.D_bend_connection_charging;...
                                app.OB_GUI_parameters.Number_ball_valves_charging;app.OB_GUI_parameters.Angle_ball_valve_charging;app.OB_GUI_parameters.D_ball_valve_charging;app.OB_GUI_parameters.Number_unions_charging;...
                                app.OB_GUI_parameters.Union_type_charging;app.OB_GUI_parameters.D_union_charging;app.OB_GUI_parameters.Number_pressure_sensors_charging;app.OB_GUI_parameters.D_pressure_sensor_charging;...
                                app.OB_GUI_parameters.Number_flow_meters_charging;app.OB_GUI_parameters.Flow_meter_type_charging;app.OB_GUI_parameters.D_flow_meter_charging;app.OB_GUI_parameters.Number_check_valves_charging;...
                                app.OB_GUI_parameters.D_check_valve_charging;app.OB_GUI_parameters.Number_90deg_elbows_charging;app.OB_GUI_parameters.D_elbow_90_charging;app.OB_GUI_parameters.Number_45deg_elbows_charging;...
                                app.OB_GUI_parameters.D_elbow_45_charging;app.OB_GUI_parameters.Number_inlets_charging;app.OB_GUI_parameters.r_inlet_rounded_charging;app.OB_GUI_parameters.Inlet_type_charging;app.OB_GUI_parameters.D_inlet_charging;...
                                app.OB_GUI_parameters.Number_outlets_charging;app.OB_GUI_parameters.D_outlet_charging;app.OB_GUI_parameters.Number_bypasses_charging;app.OB_GUI_parameters.D_bypass;...
                                app.OB_GUI_parameters.Inlet_type_bypass_charging;app.OB_GUI_parameters.r_inlet_rounded_bypass_charging;};
                
            elseif app.table_mode=="discharging_parameters"
                if indices(1)==1
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_90deg_bends_discharging = newData;
                    end
                elseif indices(1) == 2
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.R_bend_discharging = newData;
                    end
                elseif indices(1) == 3
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_bend_discharging = newData;
                    end
                elseif indices(1) == 4
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_bend_connection_discharging = newData;
                    end
                elseif indices(1) == 5
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_ball_valves_discharging = newData;
                    end
                elseif indices(1) == 6
                    if newData<0 || newData >90
                        uialert(app.UIFigure,'Please use a value between 0 and 90 degrees','Input Error');
                    else
                        app.OB_GUI_parameters.Angle_ball_valve_discharging = newData;
                    end
                elseif indices(1) == 7
                    if newData<0 || newData >90
                        uialert(app.UIFigure,'Please use a value between 0 and 90 degrees','Input Error');
                    else
                        app.OB_GUI_parameters.D_ball_valve_discharging = newData;
                    end
                elseif indices(1) == 8
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_unions_discharging = newData;
                    end
                elseif indices(1) == 9
                    if newData ~= 1 && newData ~= 2
                        uialert(app.UIFigure,'Please use type 1 = threaded or type 2 = flanged','Input Error');
                    else
                        app.OB_GUI_parameters.Union_type_discharging = newData;
                    end
                elseif indices(1) == 10
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_union_discharging = newData;
                    end
                elseif indices(1) == 11
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_pressure_sensors_discharging = newData;
                    end
                elseif indices(1) == 12
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_pressure_sensor_discharging = newData;
                    end
                elseif indices(1) == 13
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_flow_meters_discharging = newData;
                    end
                elseif indices(1) == 14
                    if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                        uialert(app.UIFigure,'Please use the flow meter type here 1 = electromagnetic, 2 = ultrasonic, 3 = turbine, 4 = paddle wheel','Input Error');
                    else
                        app.OB_GUI_parameters.Flow_meter_type_discharging = newData;
                    end
                elseif indices(1) == 15
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_flow_meter_discharging = newData;
                    end
                elseif indices(1) == 16
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_check_valves_discharging = newData;
                    end
                elseif indices(1) == 17
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_check_valve_discharging = newData;
                    end
                elseif indices(1) == 18
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_90deg_elbows_discharging = newData;
                    end
                elseif indices(1) == 19
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_elbow_90_discharging = newData;
                    end
                elseif indices(1) == 20
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_45deg_elbows_discharging = newData;
                    end
                elseif indices(1) == 21
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_elbow_45_discharging = newData;
                    end
                elseif indices(1) == 22
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_inlets_discharging = newData;
                    end
                elseif indices(1) == 23
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.r_inlet_rounded_discharging = newData;
                    end
                elseif indices(1) == 24
                    if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                        uialert(app.UIFigure,'Please use the inlet type here, 1 = square edge, 2 = rounded erge, 3 = extended edge, 4 = chamfered edge','Input Error');
                    else
                        app.OB_GUI_parameters.Inlet_type_discharging = newData;
                    end
                elseif indices(1) == 25
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_inlet_discharging = newData;
                    end
                elseif indices(1) == 26
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_outlets_discharging = newData;
                    end
                elseif indices(1) == 27
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_outlet_discharging = newData;
                    end
                elseif indices(1) == 28
                    if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_bypasses_discharging = newData;
                    end
                elseif indices(1) == 29
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_bypass = newData;
                    end
                elseif indices(1) == 30
                    if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                        uialert(app.UIFigure,'Please use the inlet type here, 1 = square edge, 2 = rounded erge, 3 = extended edge, 4 = chamfered edge','Input Error');
                    else
                        app.OB_GUI_parameters.Inlet_type_bypass_discharging = newData;
                    end
                elseif indices(1) == 31
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.r_inlet_rounded_bypass_discharging = newData;
                    end
                end

                app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_discharging;app.OB_GUI_parameters.R_bend_discharging;app.OB_GUI_parameters.D_bend_discharging;app.OB_GUI_parameters.D_bend_connection_discharging;...
                                app.OB_GUI_parameters.Number_ball_valves_discharging;app.OB_GUI_parameters.Angle_ball_valve_discharging;app.OB_GUI_parameters.D_ball_valve_discharging;app.OB_GUI_parameters.Number_unions_discharging;...
                                app.OB_GUI_parameters.Union_type_discharging;app.OB_GUI_parameters.D_union_discharging;app.OB_GUI_parameters.Number_pressure_sensors_discharging;app.OB_GUI_parameters.D_pressure_sensor_discharging;...
                                app.OB_GUI_parameters.Number_flow_meters_discharging;app.Flow_meter_type_discharging;app.OB_GUI_parameters.D_flow_meter_discharging;app.OB_GUI_parameters.Number_check_valves_discharging;...
                                app.OB_GUI_parameters.D_check_valve_discharging;app.OB_GUI_parameters.Number_90deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_90_discharging;app.OB_GUI_parameters.Number_45deg_elbows_discharging;...
                                app.OB_GUI_parameters.D_elbow_45_discharging;app.OB_GUI_parameters.Number_inlets_discharging;app.OB_GUI_parameters.r_inlet_rounded_discharging;app.OB_GUI_parameters.Inlet_type_discharging;app.OB_GUI_parameters.D_inlet_discharging;...
                                app.OB_GUI_parameters.Number_outlets_discharging;app.OB_GUI_parameters.D_outlet_discharging;app.OB_GUI_parameters.Number_bypasses_discharging;app.OB_GUI_parameters.D_bypass;...
                                app.OB_GUI_parameters.Inlet_type_bypass_discharging;app.OB_GUI_parameters.r_inlet_rounded_bypass_discharging;};

              elseif app.table_mode=="main_parameters"
                if indices(1)==1
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.P_electrical = newData;
                    end
                elseif indices(1) == 2
                    if newData<=0 || newData>1
                        uialert(app.UIFigure,'Please use a value between 0 and 1','Input Error');
                    else
                        app.OB_GUI_parameters.N_pump = newData;
                    end
                elseif indices(1) == 3
                    if newData<=0 || newData>1
                        uialert(app.UIFigure,'Please use a value between 0 and 1','Input Error');
                    else
                        app.OB_GUI_parameters.N_turbine = newData;
                    end
                elseif indices(1) == 4
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.Depth = newData;
                    end
                elseif indices(1) == 5
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.Lout = newData;
                        app.OB_GUI_parameters.V_rigid_in = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lin; %[m^3] volume inner part rigid reservoir
                        app.OB_GUI_parameters.V_rigid_out = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lout; %[m^3] volume outer part rigid reservoir
                        app.OB_GUI_parameters.Capacity_rigid = app.OB_GUI_parameters.V_rigid_in + app.OB_GUI_parameters.V_rigid_out; %[m^3] total volume rigid reservoir
                    end
                elseif indices(1) == 6
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.Lin = newData;
                        app.OB_GUI_parameters.V_rigid_in = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lin; %[m^3] volume inner part rigid reservoir
                        app.OB_GUI_parameters.V_rigid_out = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lout; %[m^3] volume outer part rigid reservoir
                        app.OB_GUI_parameters.Capacity_rigid = app.OB_GUI_parameters.V_rigid_in + app.OB_GUI_parameters.V_rigid_out; %[m^3] total volume rigid reservoir
                    end
                elseif indices(1) == 7
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_rigid = newData;
                        app.OB_GUI_parameters.V_rigid_in = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lin; %[m^3] volume inner part rigid reservoir
                        app.OB_GUI_parameters.V_rigid_out = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lout; %[m^3] volume outer part rigid reservoir
                        app.OB_GUI_parameters.Capacity_rigid = app.OB_GUI_parameters.V_rigid_in + app.OB_GUI_parameters.V_rigid_out; %[m^3] total volume rigid reservoir
                    end
                elseif indices(1) == 8
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.L_pipeline_discharging = newData;
                    end
                elseif indices(1) == 9
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.D_pipeline = newData;
                    end
                elseif indices(1) == 10
                    if newData<=0
                        uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                    else
                        app.OB_GUI_parameters.L_pipeline_charging = newData;
                    end
                end


                elseif app.table_mode=="turbine"
                    if indices(1)==1
                        if newData<=0 || newData>1
                            uialert(app.UIFigure,'Please use a value between 0 and 1','Input Error');
                        else
                            app.OB_GUI_parameters.N_turbine = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_turbine = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.N_turbine; app.OB_GUI_parameters.D_turbine};
                    
                elseif app.table_mode=="pump"
                    if indices(1)==1
                        if newData<=0 
                            uialert(app.UIFigure,'Please use a possitive value','Input Error');
                        else
                            app.OB_GUI_parameters.P_electrical = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0 || newData>1
                            uialert(app.UIFigure,'Please use a value between 0 and 1','Input Error');
                        else
                            app.OB_GUI_parameters.N_motor = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0 || newData>1
                            uialert(app.UIFigure,'Please use a value between 0 and 1','Input Error');
                        else
                            app.OB_GUI_parameters.N_pump = newData;
                        end
                    end


                app.UITable.Data={app.OB_GUI_parameters.P_electrical; app.OB_GUI_parameters.N_motor; app.OB_GUI_parameters.N_pump}

                elseif app.table_mode=="flow_sensor_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                        uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                    else
                        app.OB_GUI_parameters.Number_flow_meters_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                            uialert(app.UIFigure,'Please use the flow meter type here 1 = electromagnetic, 2 = ultrasonic, 3 = turbine, 4 = paddle wheel','Input Error');
                        else
                            app.OB_GUI_parameters.Flow_meter_type_discharging = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_flow_meter_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_flow_meters_discharging;app.OB_GUI_parameters.Flow_meter_type_discharging;app.OB_GUI_parameters.D_flow_meter_discharging};

                elseif app.table_mode=="ball_valve_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_ball_valve_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData <0 || newData >90
                            uialert(app.UIFigure,'Please use a value between 0 and 90 degrees','Input Error');
                        else
                            app.OB_GUI_parameters.Angle_ball_valve_discharging = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_ball_valve_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_ball_valves_dischagring; app.OB_GUI_parameters.Angle_ball_valve_discharging;app.OB_GUI_parameters.D_ball_valve_discharging};

                elseif app.table_mode=="check_valve_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_check_valve_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_check_valve_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_check_valves_discharging;app.OB_GUI_parameters.D_check_valve_discharging;};

                elseif app.table_mode=="45_degree_elbow_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_45deg_elbows_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_elbow_45_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_45deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_45_discharging;};

                elseif app.table_mode=="90_degree_elbow_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_90deg_elbows_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_elbow_90_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_90deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_90_discharging;};

                elseif app.table_mode=="union_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_union_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData ~= 1 && newData ~= 2
                            uialert(app.UIFigure,'Please use type 1 = threaded or type 2 = flanged','Input Error');
                        else
                            app.OB_GUI_parameters.Union_type_discharging = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_union_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_unions_discharging;app.OB_GUI_parameters.Union_type_discharging;app.OB_GUI_parameters.D_union_discharging;};

                elseif app.table_mode=="pressure_sensor_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_pressure_sensors_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_pressure_sensor_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_pressure_sensors_discharging;app.OB_GUI_parameters.D_pressure_sensor_discharging;};

                elseif app.table_mode=="90_degree_bend_discharging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_90deg_bends_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.R_bend_discharging = newData;
                        end
                    elseif indices(1)== 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_bend_discharging = newData;
                        end
                    elseif indices(1)== 4
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_bend_connection_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_discharging;app.OB_GUI_parameters.R_bend_discharging;app.OB_GUI_parameters.D_bend_discharging;app.OB_GUI_parameters.D_bend_connection_discharging;}; 

                elseif app.table_mode=="inlet_discharching"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_inlets_discharging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.r_inlet_rounded_discharging = newData;
                        end
                    elseif indices(1) == 3
                        if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                            uialert(app.UIFigure,'Please use the inlet type here, 1 = square edge, 2 = rounded erge, 3 = extended edge, 4 = chamfered edge','Input Error');
                        else
                            app.OB_GUI_parameters.Inlet_type_discharging = newData;
                        end
                    elseif indices(1) == 4
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_inlet_discharging = newData;
                        end
                    elseif indices(1) == 5
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_outlets_discharging = newData;
                        end
                    elseif indices(1) == 6
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_outlet_discharging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_inlets_discharging;app.OB_GUI_parameters.r_inlet_rounded_discharging;app.OB_GUI_parameters.Inlet_type_discharging;app.OB_GUI_parameters.D_inlet_discharging;app.OB_GUI_parameters.Number_outlets_discharging;app.OB_GUI_parameters.D_outlet_discharging;};

                elseif app.table_mode=="flow_sensor_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_flow_meters_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                            uialert(app.UIFigure,'Please use the flow meter type here 1 = electromagnetic, 2 = ultrasonic, 3 = turbine, 4 = paddle wheel','Input Error');
                        else
                            app.OB_GUI_parameters.Flow_meter_type_charging = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_flow_meter_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_flow_meters_charging;app.OB_GUI_parameters.Flow_meter_type_charging;app.OB_GUI_parameters.D_flow_meter_charging;};

                elseif app.table_mode=="ball_valve_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_ball_valve_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData <0 || newData >90
                            uialert(app.UIFigure,'Please use a value between 0 and 90 degrees','Input Error');
                        else
                            app.OB_GUI_parameters.Angle_ball_valve_charging = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_ball_valve_charging = newData;
                        end
                    end

                app.UITable.Data={ app.OB_GUI_parameters.Number_ball_valve_charging;app.OB_GUI_parameters.Angle_ball_valve_charging; app.OB_GUI_parameters.D_ball_valve_charging;};

                elseif app.table_mode=="check_valve_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_check_valve_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_check_valve_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_check_valve_charging;app.OB_GUI_parameters.D_check_valve_charging;};

                elseif app.table_mode=="45_degree_elbow_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_45deg_elbows_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_elbow_45_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_45deg_elbows_charging;app.OB_GUI_parameters.D_elbow_45_charging;};

                elseif app.table_mode=="90_degree_elbow_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_90deg_elbows_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_elbow_90_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_90deg_elbows_charging;app.OB_GUI_parameters.D_elbow_90_charging;};

                elseif app.table_mode=="union_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_union_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData ~= 1 && newData ~= 2
                            uialert(app.UIFigure,'Please use type 1 = threaded or type 2 = flanged','Input Error');
                        else
                            app.OB_GUI_parameters.Union_type_charging = newData;
                        end
                    elseif indices(1) == 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_union_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_union_charging; app.OB_GUI_parameters.Union_type_charging;app.OB_GUI_parameters.D_union_charging ;};

                elseif app.table_mode=="pressure_sensor_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_pressure_sensors_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_pressure_sensor_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_pressure_sensors_charging ;app.OB_GUI_parameters.D_pressure_sensor_charging;};

                elseif app.table_mode=="90_degree_bend_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_90deg_bends_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.R_bend_charging = newData;
                        end
                    elseif indices(1)== 3
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_bend_charging = newData;
                        end
                    elseif indices(1)== 4
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_bend_connection_charging = newData;
                        end
                    end

                app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_charging;app.OB_GUI_parameters.R_bend_charging;app.OB_GUI_parameters.D_bend_charging;app.OB_GUI_parameters.D_bend_connection_charging;};

                elseif app.table_mode=="inlet_charging"
                    if indices(1)==1
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_inlets_charging = newData;
                        end
                    elseif indices(1) == 2
                        if newData<=0
                            uialertt(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.r_inlet_rounded_charging = newData;
                        end
                    elseif indices(1) == 3
                        if newData ~= 1 && newData ~= 2 && newData ~= 3 && newData ~= 4
                            uialert(app.UIFigure,'Please use the inlet type here, 1 = square edge, 2 = rounded erge, 3 = extended edge, 4 = chamfered edge','Input Error');
                        else
                            app.OB_GUI_parameters.Inlet_type_charging = newData;
                        end
                    elseif indices(1) == 4
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_inlet_charging = newData;
                        end
                    elseif indices(1) == 5
                        if mod(newData,1)>0 || newData<0
                            uialert(app.UIFigure,'Please use a natural number as input','Input Error');
                        else
                            app.OB_GUI_parameters.Number_outlets_charging = newData;
                        end
                    elseif indices(1) == 6
                        if newData<=0
                            uialert(app.UIFigure,'Please use a possitive value as input','Input Error');
                        else
                            app.OB_GUI_parameters.D_outlet_charging = newData;
                        end
                    end
                    
                    app.UITable.Data={app.OB_GUI_parameters.Number_inlets_charging;app.OB_GUI_parameters.r_inlet_rounded_charging; app.OB_GUI_parameters.Inlet_type_charging;app.OB_GUI_parameters.D_inlet_charging;app.OB_GUI_parameters.Number_outlets_charging ;app.OB_GUI_parameters.D_outlet_charging;};                
            end

        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
        %%
        message={'The simulation has started!','The system in now in the charging phase, this might take a while'};
        uialert(app.UIFigure,message,'Simulating','Icon','info');
        
        %%
        [app.T_empty_charging, app.E_elec_in_kWh,app.H_loss_total_charging, ...
        app.H_loss_major_charging, app.H_loss_minor_charging, app.H_loss_major_umbilical_charging,...
        app.H_loss_minor_umbilical_charging, app.H_static_charging, app.H_pump, app.Q_pump, app.V_wat_rigid_charging, app.E_elec_in_J, app.i]=Charging(app.OB_GUI_parameters);

        app.EnergySavedkWhEditField.Value=app.E_elec_in_kWh;

        if app.graph_switch == 1

            % Plot 1: head losses
            plot(app.UIAxes,(1:app.i-1),app.H_loss_total_charging);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,(1:app.i-1),app.H_loss_minor_charging);
            plot(app.UIAxes,(1:app.i-1),app.H_loss_major_charging);
            plot(app.UIAxes,(1:app.i-1),app.H_loss_major_umbilical_charging);
            plot(app.UIAxes,(1:app.i-1),app.H_loss_minor_umbilical_charging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes,'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical','Location','best');
            title(app.UIAxes,'Head loss (Charging)');
    
            % Plot 2: pump head
            plot(app.UIAxes_2,(1:app.i-1),app.H_static_charging);
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,(1:app.i-1),app.H_pump);
            plot(app.UIAxes_2,(1:app.i-1),app.H_loss_total_charging);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2,'Static head','Pump head','Total head loss','Location','best')
            title(app.UIAxes_2,'Pump head (Charging)')
    
            % Plot 3 Flow through the pump
            plot(app.UIAxes_3,app.Q_pump);
            xlabel(app.UIAxes_3,'Time [ds]');
            ylabel(app.UIAxes_3,'Volumetric flowrate [m^3/s]')
            title(app.UIAxes_3,'Flow through the pump(Charging)')
    
            % Plot 4: volume of the fluid present in the rigid reservoir
            plot(app.UIAxes_4,app.V_wat_rigid_charging);
            xlabel(app.UIAxes_4,'Time [ds]');
            ylabel(app.UIAxes_4,'Volume [m^3]')
            title(app.UIAxes_4,'Volume of fluid present in the rigid reservoir(Charging)')

        end

        [app.H_loss_total_discharging, app.H_loss_minor_discharging, app.H_loss_major_discharging, app.H_loss_major_umbilical_discharging, app.H_loss_minor_umbilical_discharging,...
            app.H_static_discharging, app.H_turbine, app.Q_turbine, app.V_wat_rigid_discharging, app.V_wat_bladder, app.P_generator, app.N_roundtrip, app.T_empty_discharging, app.E_elec_out_kWh, app.i_discharging]=Discharging(app.OB_GUI_parameters, app.V_wat_rigid_charging,app.E_elec_in_J);
        app.EnergyProducedkWhEditField.Value=app.E_elec_out_kWh;
        app.EfficiencyoftheSystemEditField.Value=app.N_roundtrip;

        if app.graph_switch == 2
        
            % Plot 
            plot(app.UIAxes,(1:app.i_discharging-1),app.H_loss_total_discharging);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,(1:app.i_discharging-1),app.H_loss_minor_discharging);
            plot(app.UIAxes,(1:app.i_discharging-1),app.H_loss_major_discharging);
            plot(app.UIAxes,(1:app.i_discharging-1),app.H_loss_major_umbilical_discharging);
            plot(app.UIAxes,(1:app.i_discharging-1),app.H_loss_minor_umbilical_discharging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes, 'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical','Location','best')
            title(app.UIAxes,'Head loss (Charging)');
    
            % Plot
            plot(app.UIAxes_2,(1:app.i_discharging-1),app.H_static_discharging);
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,(1:app.i_discharging-1),app.H_loss_total_discharging);
            plot(app.UIAxes_2,(1:app.i_discharging-1),app.H_turbine);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2, 'Static head','Total head loss','Turbine head','Location','best')
            title(app.UIAxes_2, 'Turbine head (Discharging)')
    
            % Plot 
            plot(app.UIAxes_3, app.Q_turbine)
            title(app.UIAxes_3, 'Flow through the turbine (Discharging)')
            xlabel(app.UIAxes_3, 'Time [ds]');
            ylabel(app.UIAxes_3, 'Volumetric flowrate [m^3/s]')
    
            % Plot 
            plot(app.UIAxes_4,(1:app.i_discharging-1),app.V_wat_rigid_discharging);
            hold(app.UIAxes_4,"on");
            plot(app.UIAxes_4,(1:app.i_discharging-1),app.V_wat_bladder);
            hold(app.UIAxes_4,"off");
            title(app.UIAxes_4, 'Water in bladder and rigid reservoir (Discharging)')
            xlabel(app.UIAxes_4, 'Time [ds]');
            ylabel(app.UIAxes_4, 'Volume [m^3]')
            legend(app.UIAxes_4, 'Rigid reservoir','Bladder')

            % Plot 
            plot(app.UIAxes_5, app.P_generator)
            title(app.UIAxes_5, 'Power of the generator (Discharging)')
            xlabel(app.UIAxes_5, 'Time [ds]');
            ylabel(app.UIAxes_5, 'Power of the generator [W]')

        end
        end

        % Callback function: not associated with a component
        function StarttestcomparisonSimulationButtonPushed(app, event)
        message={'The test comparison simulation has started!','The system in now in the discharging phase, this might take a while'};
        uialert(app.UIFigure,message,'Simulating','Icon','info');
        
        %%
        [app.H_loss_total_discharging, app.H_loss_minor_discharging, app.H_loss_major_discharging,app.H_static_discharging, app.H_turbine, app.Q_turbine, app.V_wat_rigid_discharging, app.T_empty_discharging, app.i_discharging]=Discharging_test_setup(app.OB_GUI_parameters);
        
        end

        % Callback function
        function ChargeDischargeDropDown_2ValueChanged(app, event)
            value = app.ChargeDischargeDropDown_2.Value;

            if value=="Charging Results"
            app.graph_switch=1;
            % Plot 1: head losses
            clf(app.UIAxes)
            plot(app.UIAxes,(1:app.i-1),app.H_loss_total_charging);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,(1:app.i-1),app.H_loss_minor_charging);
            plot(app.UIAxes,(1:app.i-1),app.H_loss_major_charging);
            plot(app.UIAxes,(1:app.i-1),app.H_loss_major_umbilical_charging);
            plot(app.UIAxes,(1:app.i-1),app.H_loss_minor_umbilical_charging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes,'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical');
            title(app.UIAxes,'Head loss (Charging)');
    
            % Plot 2: pump head
            clf(app.UIAxes_2)
            plot(app.UIAxes_2,(1:app.i-1),app.H_static_charging);
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,(1:app.i-1),app.H_pump);
            plot(app.UIAxes_2,(1:app.i-1),app.H_loss_total_charging);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2,'Static head','Pump head','Total head loss')
            title(app.UIAxes_2,'Pump head (Charging)')
    
            % Plot 3 Flow through the pump
            clf(app.UIAxes_3)
            plot(app.UIAxes_3,app.Q_pump);
            xlabel(app.UIAxes_3,'Time [ds]');
            ylabel(app.UIAxes_3,'Volumetric flowrate [m^3/s]')
            title(app.UIAxes_3,'Flow through the pump(Charging)')
    
            % Plot 4: volume of the fluid present in the rigid reservoir
            clf(app.UIAxes_4)
            plot(app.UIAxes_4,app.V_wat_rigid_charging);
            xlabel(app.UIAxes_4,'Time [ds]');
            ylabel(app.UIAxes_4,'Volume [m^3]')
            title(app.UIAxes_4,'Volume of fluid present in the rigid reservoir(Charging)')


            elseif value=="Discharging Results"
                app.graph_switch=2;
                
            % Plot
            clf(app.UIAxes)
            plot(app.UIAxes,app.H_loss_total_discharging);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.H_loss_minor_discharging);
            plot(app.UIAxes,app.H_loss_major_discharging);
            plot(app.UIAxes,app.H_loss_major_umbilical_discharging);
            plot(app.UIAxes,app.H_loss_minor_umbilical_discharging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes, 'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical')
            title(app.UIAxes,'Head loss (Charging)');
    
            % Plot
            clf(app.UIAxes_2)
            plot(app.UIAxes_2,app.H_static_discharging);
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,app.H_loss_total_discharging);
            plot(app.UIAxes_2,app.H_turbine);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2, 'Static head','Total head loss','Turbine head')
            title(app.UIAxes_2, 'Turbine head (Discharging)')
    
            % Plot 
            clf(app.UIAxes_3)
            plot(app.UIAxes_3, app.Q_turbine)
            title(app.UIAxes_3, 'Flow through the turbine (Discharging)')
            xlabel(app.UIAxes_3, 'Time [ds]');
            ylabel(app.UIAxes_3, 'Volumetric flowrate [m^3/s]')
    
            % Plot 
            clf(app.UIAxes_4)
            plot(app.UIAxes_4,(1:app.i_discharging-1),app.V_wat_rigid_discharging);
            hold(app.UIAxes_4,"on");
            plot(app.UIAxes_4,(1:app.i_discharging-1),app.V_wat_bladder);
            hold(app.UIAxes_4,"off");
            title(app.UIAxes_4, 'Water in bladder and rigid reservoir (Discharging)')
            xlabel(app.UIAxes_4, 'Time [ds]');
            ylabel(app.UIAxes_4, 'Volume [m^3]')
            legend(app.UIAxes_4, 'Rigid reservoir','Bladder')

%             % Plot 
%             plot(app.UIAxes_5, app.P_generator)
%             title(app.UIAxes_5, 'Power of the generator (Discharging)')
%             xlabel(app.UIAxes_5, 'Time [ds]');
%             ylabel(app.UIAxes_5, 'Power of the generator [W]')

           
            end 
        end

        % Button down function: ChargingTab
        function ChargingTabButtonDown(app, event)
            % Show images
            app.ImageChargingBig.ImageSource="charging.png";
            app.ImageChargingBig.ScaleMethod='stretch';
            app.ImageChargingLegend.ImageSource="legend.png";
            app.ImageChargingLegend.ScaleMethod='stretch';
            app.ImageChargingSmall.ImageSource="inlet.png";
            app.ImageChargingSmall.ScaleMethod='stretch';

            % Plot results
            app.graph_switch=1;
            
            % Plot 1: head losses
            cla(app.UIAxes)
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.H_loss_total_charging);
            plot(app.UIAxes,app.H_loss_minor_charging);
            plot(app.UIAxes,app.H_loss_major_charging);
            plot(app.UIAxes,app.H_loss_major_umbilical_charging);
            plot(app.UIAxes,app.H_loss_minor_umbilical_charging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes,'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical','Location','best');
            title(app.UIAxes,'Head loss (Charging)');
    
            % Plot 2: pump head
            cla(app.UIAxes_2)
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,app.H_static_charging);
            plot(app.UIAxes_2,app.H_pump);
            plot(app.UIAxes_2,app.H_loss_total_charging);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2,'Static head','Pump head','Total head loss','Location','best')
            title(app.UIAxes_2,'Pump head (Charging)')
    
            % Plot 3 Flow through the pump
            cla(app.UIAxes_3)
            plot(app.UIAxes_3,app.Q_pump);
            xlabel(app.UIAxes_3,'Time [ds]');
            ylabel(app.UIAxes_3,'Volumetric flowrate [m^3/s]')
            title(app.UIAxes_3,'Flow through the pump(Charging)')
    
            % Plot 4: volume of the fluid present in the rigid reservoir
            cla(app.UIAxes_4)
            plot(app.UIAxes_4,app.V_wat_rigid_charging);
            xlabel(app.UIAxes_4,'Time [ds]');
            ylabel(app.UIAxes_4,'Volume [m^3]')
            title(app.UIAxes_4,'Volume of fluid present in the rigid reservoir(Charging)')
            legend(app.UIAxes_4, 'Rigid reservoir','Location','best')
        end

        % Button down function: DischargingTab
        function DischargingTabButtonDown(app, event)
            % Show images
            app.ImageDischargingBig.ImageSource="discharging.png";
            app.ImageDischargingBig.ScaleMethod='stretch';
            app.ImageDischargingLegend.ImageSource="legend.png";
            app.ImageDischargingLegend.ScaleMethod='stretch';
            app.ImageDischargingSmall.ImageSource="inlet.png";
            app.ImageDischargingSmall.ScaleMethod='stretch';

            % Plot results
 
            % Plot 
            cla(app.UIAxes)
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.H_loss_total_discharging);
            plot(app.UIAxes,app.H_loss_minor_discharging);
            plot(app.UIAxes,app.H_loss_major_discharging);
            plot(app.UIAxes,app.H_loss_major_umbilical_discharging);
            plot(app.UIAxes,app.H_loss_minor_umbilical_discharging);
            hold(app.UIAxes,"off");
            xlabel(app.UIAxes, 'Time [ds]');
            ylabel(app.UIAxes, 'Head loss [m]');
            legend(app.UIAxes, 'Total head loss','Minor head loss','Major head loss','Major head loss umbilical','Minor head loss umbilical','Location','best')
            title(app.UIAxes,'Head loss (Discharging)');
    
            % Plot 
            cla(app.UIAxes_2)
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,app.H_static_discharging);
            plot(app.UIAxes_2,app.H_loss_total_discharging);
            plot(app.UIAxes_2,app.H_turbine);
            hold(app.UIAxes_2,"off");
            xlabel(app.UIAxes_2, 'Time [ds]');
            ylabel(app.UIAxes_2,'Head [m]')
            legend(app.UIAxes_2, 'Static head','Total head loss','Turbine head','Location','best')
            title(app.UIAxes_2, 'Turbine head (Discharging)')
    
            % Plot 
            cla(app.UIAxes_3)
            plot(app.UIAxes_3, app.Q_turbine)
            title(app.UIAxes_3, 'Flow through the turbine (Discharging)')
            xlabel(app.UIAxes_3, 'Time [ds]');
            ylabel(app.UIAxes_3, 'Volumetric flowrate [m^3/s]')
    
            % Plot 
            cla(app.UIAxes_4)
            hold(app.UIAxes_4,"on");
            plot(app.UIAxes_4,app.V_wat_rigid_discharging);
            plot(app.UIAxes_4,app.V_wat_bladder);
            hold(app.UIAxes_4,"off")
            title(app.UIAxes_4, 'Water in bladder and rigid reservoir (Discharging)')
            xlabel(app.UIAxes_4, 'Time [ds]');
            ylabel(app.UIAxes_4, 'Volume [m^3]')
            legend(app.UIAxes_4, 'Rigid reservoir','Bladder','Location','best')

%             % Plot 
%             plot(app.UIAxes_5, app.P_generator)
%             title(app.UIAxes_5, 'Power of the generator (Discharging)')
%             xlabel(app.UIAxes_5, 'Time [ds]');
%             ylabel(app.UIAxes_5, 'Power of the generator [W]')
        end

        % Value changed function: SubpartChargingDropDown
        function SubpartChargingDropDownValueChanged(app, event)
            value = app.SubpartChargingDropDown.Value;
            if value == "Inlet"
                app.TextArea.Value="Inlets are entrances of a pipe system. The head loss that is incurred when entering a pipe system depends on the inlet type. Four inlet types are dis-played in the figure. The square inlet (a) is similar to a sudden contraction.Because A1 >> A2, the ratio A2 drops to zero. This results in a coefficient of contraction of 0.585. The A1minor loss coefficient for such an inlet equals 0.503. Inlet b is a rounded edge inlet. Its minor loss coefficient is dependent on the ratio between the pipe diameter d and the edge radius r. The extended edge inlet (c) has an edge that extends into the reservoir. This extension creates turbulence, this inlet type is therefore less efficient than the other inlet types. The extension has practical use in engineering a piping system. The entrance can be screwed into a reservoir wall and be detached or replaced later, while the other kinds of inlets are more permanently fixed. Its minor loss coefficient equals 0.78. Inlets with a chamfered edge (d) have a minor loss coefficient of 0.25. ";
                app.ImageChargingSmall.ImageSource="inlet.png";
                app.ImageChargingSmall.ScaleMethod='stretch';
                
                % Changing the input table based on selected sub-part
                app.table_mode="inlet_charging";
            app.UITable.RowName=["Number_inlets","r_inlet_rounded","Inlet_type","D_inlet_discharging"...
                "Number_outlets","D_outlet",];
            app.UITable.Data={app.OB_GUI_parameters.Number_inlets_charging;app.OB_GUI_parameters.r_inlet_rounded_charging;app.OB_GUI_parameters.Inlet_type_charging;app.OB_GUI_parameters.D_inlet_charging;...
                app.OB_GUI_parameters.Number_outlets_charging;app.OB_GUI_parameters.D_outlet_charging;};

            elseif value == "90 Degree Bend"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "A 90-degree bend changes the direction of the fluid in the system in a gradual manner. The bends used in the current prototype of the Ocean Battery experience a contraction at the start of the bend and an enlargement at the end of the bend, because the pipe connected to it has a larger inside diameter. The K-value depends on the radius of the bend, the frictional factor of the bend, the angle of the bend and the reynolds number of the fluid in the bend.";
                app.ImageChargingSmall.ImageSource="bend.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="90_degree_bend_charging";
            app.UITable.RowName=["Number_90deg_bends","R_bend","D_bend","D_bend_connection",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_charging;app.OB_GUI_parameters.R_bend_charging;app.OB_GUI_parameters.D_bend_charging;app.OB_GUI_parameters.D_bend_connection_charging;};
            
            elseif value == "Pressure Sensor"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The minor loss coefficient of pressure sensors is assumed to be similar to the minor loss coefficient of a threaded union. A value of 0.08 is therefore used as the minor loss coefficient for pressure sensors.";
                app.ImageChargingSmall.ImageSource="pressure_sensor.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="pressure_sensor_charging";
            app.UITable.RowName=["Number_pressure_sensors","D_pressure_sensor",];
            app.UITable.Data={app.OB_GUI_parameters.Number_pressure_sensors_charging;app.OB_GUI_parameters.D_pressure_sensor_charging;};

            elseif value == "Tee Piece"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "Tee junctions are used to join or separate flows. There are four possible ways to utilize a tee junction, which are displayed in the figure above. The minor loss coefficients of combining tees (a) and a separating tees (b) equals 4, while the minor loss coefficients for outbranching (c) and inbranching (d) tees equals 2,9.";
                app.ImageChargingSmall.ImageSource="tee_junctions.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="tee_piece_charging";
            app.UITable.RowName=[];
            app.UITable.Data={};

            elseif value == "Union"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The connection between two pipes causes a minor head loss because it is not as smooth as regular pipe wall. The minor loss coefficient for threaded unions is found to be 0.08, while the minor loss coefficient for flanged unions is found to be 0.04.";
                app.ImageChargingSmall.ImageSource="union.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="union_charging";
            app.UITable.RowName=["Number_unions","Union_type","D_union",];
            app.UITable.Data={app.OB_GUI_parameters.Number_unions_charging;app.OB_GUI_parameters.Union_type_charging;app.OB_GUI_parameters.D_union_charging;};
            
            elseif value == "90 Degree Elbow"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The minor loss coefficient of 90 degree-elbows with threaded unions has been determined be equal to 1.3 or 1.5. The pipe diameter d [m], pipe radius r [m], elbow radius R [m] and elbow angle θ [◦] are indicated in the figure above. The difference between an elbow and a bend is that the turn of an elbow is sudden, while the turn of a bend is gradual.";
                app.ImageChargingSmall.ImageSource="elbow.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="90_degree_elbow_charging";
            app.UITable.RowName=["Number_90deg_elbows","D_elbow_90",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_elbows_charging;app.OB_GUI_parameters.D_elbow_90_charging;};

            elseif value == "45 Degree Elbow"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The minor loss coefficient of 45-degree elbows has been determined to be 0.4 or 0.45.";
                app.ImageChargingSmall.ImageSource="45_degree_elbow.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="45_degree_elbow_charging";
            app.UITable.RowName=["Number_45deg_elbows","D_elbow_45",];
            app.UITable.Data={app.OB_GUI_parameters.Number_45deg_elbows_charging;app.OB_GUI_parameters.D_elbow_45_charging;};

            elseif value == "Check Valve"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "A check valve prevents fluid flow in undesired directions. The minor loss coefficient of a check valve is determined to be equal to 2 when a fluid passes in the desired desired direction. When a flow tries to pass the valve in the opposite direction, the valve closes. This results in in infinitely large minor loss coefficient. No fluid is allowed to pass.";
                app.ImageChargingSmall.ImageSource="check_valve.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="check_valve_charging";
            app.UITable.RowName=["Number_check_valves","D_check_valve",];
            app.UITable.Data={app.OB_GUI_parameters.Number_check_valves_charging;app.OB_GUI_parameters.D_check_valve_charging;};

%           elseif value == "Connection"
%                 app.TextArea.Value = "";
            elseif value == "Ball Valve"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "Ball valves are used to control the flow through fluid systems. The minor head loss of a ball valve is dependent on the angle of the valve. Four possible states are displayed. d represents the inside diameter [m] and θ represents the angle of the valve [◦]. Flow through the valve is nearly unrestricted if the vale is open (a) and not possible if the valve is closed (b). The flow is restricted if the valve is partially closed (c) and near impossible if the valve is almost closed (d)";
                app.ImageChargingSmall.ImageSource="ball_valve.png";
                app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="ball_valve_charging";
            app.UITable.RowName=["Number_ball_valves","Angle_ball_valve","D_ball_valve","Number_unions",];
            app.UITable.Data={app.OB_GUI_parameters.Number_ball_valves_charging;app.OB_GUI_parameters.Angle_ball_valve_charging;app.OB_GUI_parameters.D_ball_valve_charging;};

            elseif value == "Flow Sensor"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "There are several types of flow meters with different kinds of minor loss coefficients. Electromagnetic- and ultrasonic flow meters have a K-value of 0. A turbine flow meter has a K-value of 0.8. A paddle wheel has a K-value of 5.5.";
              app.ImageChargingSmall.ImageSource="flow_sensor.png";
              app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="flow_sensor_charging";
            app.UITable.RowName=["Number_flow_meters","Flow_meter_type","D_flow_meter",];
            app.UITable.Data={app.OB_GUI_parameters.Number_flow_meters_charging;app.Flow_meter_type_charging;app.OB_GUI_parameters.D_flow_meter_charging;};

            elseif value == "Pump"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "When the system is charging, a motor uses the electricity from a renewable energy source to power the pump, such that water is pumped from the rigid reservoir to the flexible bladder. The fluid is thereby elevated and also experiences hydrostatic pressure from the surrounding seawater. This process requires a certain amount of work [N m−1], which is its weight [N] times the height it is lifted [m]. The energy needed for this process is expressed in units of [m], which is equal to the elevation gain. This is called the elevation or potential head.";
              app.ImageChargingSmall.ImageSource="pump.png";
              app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="pump";
            app.UITable.RowName=["P_electrical","N_motor","N_pump",];
            app.UITable.Data={app.OB_GUI_parameters.P_electrical; app.OB_GUI_parameters.N_motor; app.OB_GUI_parameters.N_pump;};
            

            elseif value == "Turbine"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value= "Multiple Francis pump-turbines will be used in the buried Ocean Battery to convert kinetic energy into electrical energy. If the turbine’s efficiency increases, it can extract more energy from the working fluid, increasing the roundtrip efficiency of the Ocean Battery. The pump-turbine works either as a pump or turbine depending on the rotation direction. The Francis turbine is a reaction turbine that uses a snail-shell shaped tube to direct the water around the rotor of the turbine.";
              app.ImageChargingSmall.ImageSource="turbine.png";
              app.ImageChargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="turbine";
            app.UITable.RowName=["N_turbine","D_turbine",];
            app.UITable.Data={app.OB_GUI_parameters.N_turbine; app.OB_GUI_parameters.D_turbine;};
            
            end
        end

        % Value changed function: SubpartDischargingDropDown
        function SubpartDischargingDropDownValueChanged(app, event)
            value = app.SubpartDischargingDropDown.Value;
            if value == "Inlet"
                app.TextArea.Value="Inlets are entrances of a pipe system. The head loss that is incurred when entering a pipe system depends on the inlet type. Four inlet types are dis-played in the figure. The square inlet (a) is similar to a sudden contraction.Because A1 >> A2, the ratio A2 drops to zero. This results in a coefficient of contraction of 0.585. The A1minor loss coefficient for such an inlet equals 0.503. Inlet b is a rounded edge inlet. Its minor loss coefficient is dependent on the ratio between the pipe diameter d and the edge radius r. The extended edge inlet (c) has an edge that extends into the reservoir. This extension creates turbulence, this inlet type is therefore less efficient than the other inlet types. The extension has practical use in engineering a piping system. The entrance can be screwed into a reservoir wall and be detached or replaced later, while the other kinds of inlets are more permanently fixed. Its minor loss coefficient equals 0.78. Inlets with a chamfered edge (d) have a minor loss coefficient of 0.25. ";
                app.ImageDischargingSmall.ImageSource="inlet.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';
                
                % Changing the input table based on selected sub-part
                app.table_mode="inlet_discharching";
            app.UITable.RowName=["Number_inlets","r_inlet_rounded","Inlet_type","D_inlet_discharging"...
                "Number_outlets","D_outlet",];
            app.UITable.Data={app.OB_GUI_parameters.Number_inlets_discharging;app.OB_GUI_parameters.r_inlet_rounded_discharging;app.OB_GUI_parameters.Inlet_type_discharging;app.OB_GUI_parameters.D_inlet_discharging;...
                app.OB_GUI_parameters.Number_outlets_discharging;app.OB_GUI_parameters.D_outlet_discharging;};

            elseif value == "90 Degree Bend"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "A 90-degree bend changes the direction of the fluid in the system in a gradual manner. The bends used in the current prototype of the Ocean Battery experience a contraction at the start of the bend and an enlargement at the end of the bend, because the pipe connected to it has a larger inside diameter. The K-value depends on the radius of the bend, the frictional factor of the bend, the angle of the bend and the reynolds number of the fluid in the bend.";
                app.ImageDischargingSmall.ImageSource="bend.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="90_degree_bend_discharging";
            app.UITable.RowName=["Number_90deg_bends","R_bend","D_bend","D_bend_connection",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_discharging;app.OB_GUI_parameters.R_bend_discharging;app.OB_GUI_parameters.D_bend_discharging;app.OB_GUI_parameters.D_bend_connection_discharging;};
            
            elseif value == "Pressure Sensor"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The minor loss coefficient of pressure sensors is assumed to be similar to the minor loss coefficient of a threaded union. A value of 0.08 is therefore used as the minor loss coefficient for pressure sensors.";
                app.ImageDischargingSmall.ImageSource="pressure_sensor.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="pressure_sensor_discharging";
            app.UITable.RowName=["Number_pressure_sensors","D_pressure_sensor",];
            app.UITable.Data={app.OB_GUI_parameters.Number_pressure_sensors_discharging;app.OB_GUI_parameters.D_pressure_sensor_discharging;};

            elseif value == "Tee Piece"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "Tee junctions are used to join or separate flows. There are four possible ways to utilize a tee junction, which are displayed in the figure above. The minor loss coefficients of combining tees (a) and a separating tees (b) equals 4, while the minor loss coefficients for outbranching (c) and inbranching (d) tees equals 2,9.";
                app.ImageDischargingSmall.ImageSource="tee_junctions.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="tee_piece_discharging";
            app.UITable.RowName=[];
            app.UITable.Data={};
            elseif value == "Union"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The connection between two pipes causes a minor head loss because it is not as smooth as regular pipe wall. The minor loss coefficient for threaded unions is found to be 0.08, while the minor loss coefficient for flanged unions is found to be 0.04.";
                app.ImageDischargingSmall.ImageSource="union.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="union_discharging";
            app.UITable.RowName=["Number_unions","Union_type","D_union",];
            app.UITable.Data={app.OB_GUI_parameters.Number_unions_discharging;app.OB_GUI_parameters.Union_type_discharging;app.OB_GUI_parameters.D_union_discharging;};
            
            elseif value == "90 Degree Elbow"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The minor loss coefficient of 90 degree-elbows with threaded unions has been determined be equal to 1.3 or 1.5. The pipe diameter d [m], pipe radius r [m], elbow radius R [m] and elbow angle θ [◦] are indicated in the figure above. The difference between an elbow and a bend is that the turn of an elbow is sudden, while the turn of a bend is gradual.";
                app.ImageDischargingSmall.ImageSource="elbow.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="90_degree_elbow_discharging";
            app.UITable.RowName=["Number_90deg_elbows","D_elbow_90",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_90_discharging;};

            elseif value == "45 Degree Elbow"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "The minor loss coefficient of 45-degree elbows has been determined to be 0.4 or 0.45.";
                app.ImageDischargingSmall.ImageSource="45_degree_elbow.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="45_degree_elbow_discharging";
            app.UITable.RowName=["Number_45deg_elbows","D_elbow_45",];
            app.UITable.Data={app.OB_GUI_parameters.Number_45deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_45_discharging;};

            elseif value == "Check Valve"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "A check valve prevents fluid flow in undesired directions. The minor loss coefficient of a check valve is determined to be equal to 2 when a fluid passes in the desired desired direction. When a flow tries to pass the valve in the opposite direction, the valve closes. This results in in infinitely large minor loss coefficient. No fluid is allowed to pass.";
                app.ImageDischargingSmall.ImageSource="check_valve.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="check_valve_discharging";
            app.UITable.RowName=["Number_check_valves","D_check_valve",];
            app.UITable.Data={app.OB_GUI_parameters.Number_check_valves_discharging;app.OB_GUI_parameters.D_check_valve_discharging;};

%           elseif value == "Connection"
%                 app.TextArea.Value = "";
            elseif value == "Ball Valve"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "Ball valves are used to control the flow through fluid systems. The minor head loss of a ball valve is dependent on the angle of the valve. Four possible states are displayed. d represents the inside diameter [m] and θ represents the angle of the valve [◦]. Flow through the valve is nearly unrestricted if the vale is open (a) and not possible if the valve is closed (b). The flow is restricted if the valve is partially closed (c) and near impossible if the valve is almost closed (d)";
                app.ImageDischargingSmall.ImageSource="ball_valve.png";
                app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="ball_valve_discharging";
            app.UITable.RowName=["Number_ball_valves","Angle_ball_valve","D_ball_valve","Number_unions",];
            app.UITable.Data={app.OB_GUI_parameters.Number_ball_valves_discharging;app.OB_GUI_parameters.Angle_ball_valve_discharging;app.OB_GUI_parameters.D_ball_valve_discharging;};

            elseif value == "Flow Sensor"
                % Changing text and picture based on selected sub-part
                app.TextArea.Value = "There are several types of flow meters with different kinds of minor loss coefficients. Electromagnetic- and ultrasonic flow meters have a K-value of 0. A turbine flow meter has a K-value of 0.8. A paddle wheel has a K-value of 5.5.";
              app.ImageDischargingSmall.ImageSource="flow_sensor.png";
              app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="flow_sensor_discharging";
            app.UITable.RowName=["Number_flow_meters","Flow_meter_type","D_flow_meter",];
            app.UITable.Data={app.OB_GUI_parameters.Number_flow_meters_discharging;app.Flow_meter_type_discharging;app.OB_GUI_parameters.D_flow_meter_discharging;};

            elseif value == "Pump"
                app.TextArea.Value = "When the system is charging, a motor uses the electricity from a renewable energy source to power the pump, such that water is pumped from the rigid reservoir to the flexible bladder. The fluid is thereby elevated and also experiences hydrostatic pressure from the surrounding seawater. This process requires a certain amount of work [N m−1], which is its weight [N] times the height it is lifted [m]. The energy needed for this process is expressed in units of [m], which is equal to the elevation gain. This is called the elevation or potential head.";
              app.ImageDischargingSmall.ImageSource="pump.png";
              app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="pump";
            app.UITable.RowName=["P_electrical","N_motor","N_pump",];
            app.UITable.Data={app.OB_GUI_parameters.P_electrical; app.OB_GUI_parameters.N_motor; app.OB_GUI_parameters.N_pump;};
            

            elseif value == "Turbine"
                app.TextArea.Value= "Multiple Francis pump-turbines will be used in the buried Ocean Battery to convert kinetic energy into electrical energy. If the turbine’s efficiency increases, it can extract more energy from the working fluid, increasing the roundtrip efficiency of the Ocean Battery. The pump-turbine works either as a pump or turbine depending on the rotation direction. The Francis turbine is a reaction turbine that uses a snail-shell shaped tube to direct the water around the rotor of the turbine.";
              app.ImageDischargingSmall.ImageSource="turbine.png";
              app.ImageDischargingSmall.ScaleMethod='stretch';

                % Changing the input table based on selected sub-part
                app.table_mode="turbine";
            app.UITable.RowName=["N_turbine","D_turbine",];
            app.UITable.Data={app.OB_GUI_parameters.N_turbine; app.OB_GUI_parameters.D_turbine;};
            
            end
        end

        % Button down function: OverviewTab
        function OverviewTabButtonDown(app, event)
            app.Image_overview.ImageSource="system.png";
            app.Image_overview.ScaleMethod='stretch';

            app.TextArea.Value="A general overview of the system is given. The following sub-parts are indicated in the system: the rigid reservoir, the flexible bladder, the pumping system, and the turbine system. Based on the entered parameters, the K-values throughout the system can be calculated.";
         
        end

        % Button pushed function: ComputeKValuesButton
        function ComputeKValuesButtonPushed(app, event)
%             %%
%             [app.K_Values]=independant_k_values(app.OB_GUI_parameters);
%             app.UITable_K_Values.ColumnName=["Contraction","Enlargement","Ball Valve",...
%                 "Unions","P-Sensor","Flow Meter","Check Valve"...
%                 "Elbow 90","Elbow 45","Inlet","Outlet"...
%                 "Bypass Out","Bypass In","Bypass",];
%             app.UITable_K_Values.RowName=["Charging";"Discharging";];
%             app.UITable_K_Values.Data={app.K_Values.K_contraction_bend_charging,app.K_Values.K_enlargement_bend_charging,app.K_Values.K_ball_valve_charging,...
%                 app.K_Values.K_unions_charging,app.K_Values.K_pressure_sensor_charging,app.K_Values.K_flow_meter_charging,app.K_Values.K_check_valve_charging,...
%                 app.K_Values.K_elbow_90_charging,app.K_Values.K_elbow_45_charging,app.K_Values.K_inlet_charging,app.K_Values.K_outlet_charging,...
%                 app.K_Values.K_outlet_bypass_charging,app.K_Values.K_inlet_bypass_charging,app.K_Values.K_bypass_charging;...
%                 app.K_Values.K_contraction_bend_discharging,app.K_Values.K_enlargement_bend_charging,app.K_Values.K_ball_valve_charging,...
%                 app.K_Values.K_unions_discharging,app.K_Values.K_pressure_sensor_discharging,app.K_Values.K_flow_meter_discharging,app.K_Values.K_check_valve_discharging,...
%                 app.K_Values.K_elbow_90_discharging,app.K_Values.K_elbow_45_discharging,app.K_Values.K_inlet_discharging,app.K_Values.K_outlet_discharging,...
%                 app.K_Values.K_outlet_bypass_discharging,app.K_Values.K_inlet_bypass_discharging,app.K_Values.K_bypass_discharging;};
%             

            %%
            [app.K_Values]=independant_k_values(app.OB_GUI_parameters);
            app.UITable_K_Values.RowName=["Contraction";"Enlargement";"Ball Valve";...
                "Unions";"P-Sensor";"Flow Meter";"Check Valve";];
            app.UITable_K_Values_2.RowName=["Elbow 90";"Elbow 45";"Inlet";"Outlet";...
                "Bypass Out";"Bypass In";"Bypass",];
            app.UITable_K_Values.ColumnName=["Charging";"Discharging";];
            app.UITable_K_Values_2.ColumnName=["Charging";"Discharging";];
            
            app.UITable_K_Values.Data={app.K_Values.K_contraction_bend_charging,app.K_Values.K_contraction_bend_discharging;app.K_Values.K_enlargement_bend_charging,app.K_Values.K_enlargement_bend_discharging;app.K_Values.K_ball_valve_charging,app.K_Values.K_ball_valve_discharging;...
                app.K_Values.K_unions_charging,app.K_Values.K_unions_discharging;app.K_Values.K_pressure_sensor_charging,app.K_Values.K_pressure_sensor_discharging;app.K_Values.K_flow_meter_charging,app.K_Values.K_flow_meter_discharging;app.K_Values.K_check_valve_charging,app.K_Values.K_check_valve_discharging;};
                
            app.UITable_K_Values_2.Data={app.K_Values.K_elbow_90_charging,app.K_Values.K_elbow_90_discharging;app.K_Values.K_elbow_45_charging,app.K_Values.K_elbow_45_discharging;app.K_Values.K_inlet_charging,app.K_Values.K_inlet_discharging;app.K_Values.K_outlet_charging,app.K_Values.K_outlet_discharging;...
                app.K_Values.K_outlet_bypass_charging,app.K_Values.K_outlet_bypass_discharging;app.K_Values.K_inlet_bypass_charging,app.K_Values.K_inlet_bypass_discharging;app.K_Values.K_bypass_charging,app.K_Values.K_bypass_discharging;};
                
        end

        % Callback function: not associated with a component
        function LoadexperimentparametersButtonPushed(app, event)
        %% Ocean Battery Parameters
            % In this script, most parameters for the system set.
            
            %%  Size parameters
            app.OB_GUI_parameters.Lout = 8.3044; %[m] Total lenght outer segment rigid reservoir
            app.OB_GUI_parameters.Lin = 2.6244; %[m] Total lenght inner segment rigid reservoir
            app.OB_GUI_parameters.D_rigid = 0.3179; %[m] Diameter outer segment rigid reservoir
            
            %% Accuracy parameters
            app.OB_GUI_parameters.Delta_t = 0.01; 

            %% Pump, turbine, motor and generator parameters
            app.OB_GUI_parameters.P_electrical = 78.78; %[W] Electrical input power.
            app.OB_GUI_parameters.N_motor = 1; %[-] Efficiency of the motor.
            app.OB_GUI_parameters.N_pump = 0.4181; %[-] Efficiency of the pump.
            app.OB_GUI_parameters.N_turbine = 0.2; %[-] Efficiency of the turbine.
            app.OB_GUI_parameters.N_generator = 1; %[-] Efficiency of the generator.
            app.OB_GUI_parameters.D_turbine = 0.034; %[m] Diameter of the turbine outlet.
            
            %% Pump, turbine, motor and generator parameters
            app.OB_GUI_parameters.D_turbine = 6e-3; %[m] Diameter of one turbnine outlet. There are three turbines.
            
            %% Pipeline parameters
            app.OB_GUI_parameters.L_pipeline_discharging = 4.175; %[m] Total length of the pipeline during the discharging phase.
            app.OB_GUI_parameters.D_pipeline = 0.0272; %[m] Diameter of the pipeline.
            app.OB_GUI_parameters.Roughness_pipeline = 0.0015e-3; %[m] Specific material roughness of the pipeline.
            
            %% Bypass parameters
            app.OB_GUI_parameters.D_bypass = 0.0426; %[m] Diamter of the bypass.
            app.OB_GUI_parameters.L_bypass = 0.579; %[m] Length of the bypass. 
            app.OB_GUI_parameters.Roughness_bypass = 0.0015e-3; %[m] Absolute roughness of the bypass.
            
            %% External parameters
            app.OB_GUI_parameters.Dens_wat = 999.15; %[kg/m^3] Density of water
            app.OB_GUI_parameters.Visc_wat = 1e-3; %[Pa*s] Viscosity of water
            app.OB_GUI_parameters.Dens_air = 1.2; %[kg/m^3] Density of air
            app.OB_GUI_parameters.Visc_air = 1.865e-5; %[Pa*s] Viscosity of air
            app.OB_GUI_parameters.P_atm = 101325; %[Pa] Atmospheric pressure
            app.OB_GUI_parameters.g = 9.81;%[m/s^2] gravitational constant
            
            %% Initial calculations
            %   It is preferred not to execute calculattions here. If it is possible,
            %   these equations will be moved to another function. 
            app.OB_GUI_parameters.V_rigid_in = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lin; %[m^3] volume inner part rigid reservoir
            app.OB_GUI_parameters.V_rigid_out = pi*(0.5*app.OB_GUI_parameters.D_rigid)^2*app.OB_GUI_parameters.Lout; %[m^3] volume outer part rigid reservoir
            app.OB_GUI_parameters.Capacity_rigid = app.OB_GUI_parameters.V_rigid_in + app.OB_GUI_parameters.V_rigid_out; %[m^3] total volume rigid reservoir
            
            %% Test parameters
            app.OB_GUI_parameters.V_wat_rigid_end = 0.30129; %[m^3] End volume of the rigid reservoir. 
            app.OB_GUI_parameters.V_tank_start = 1.2*1*0.415; %[m^3] Starting volume of the water in the tank. 
            app.OB_GUI_parameters.A_tank = 1.2*1; %[m^2] Surface area of the tank.
            app.OB_GUI_parameters.h1 = 4.295; %[m] Bottom tank to floor.
            app.OB_GUI_parameters.h2 = 0.475; %[m] Bottom rigid reservoir to floor.
            
            app.OB_GUI_parameters.D_pipeline_test = 0.0426; %[m] Diameter of the pipeline from the test setup.
            app.OB_GUI_parameters.Roughness_pipeline_test = 0.0015e-3; %[m] Absolute roughness of the pipeline from the test setup.
            app.OB_GUI_parameters.L_pipeline_test = 4.175; %[m] Length of the pipeline from the test setup.
            
            %% Ball valve
            app.OB_GUI_parameters.t_open_ball_valve = 12;

            % %% Parameters for minor loss test setup 
            % 
            %% 90 degree bend
            app.OB_GUI_parameters.Number_90deg_bends_discharging = 1; %[-] Select the number of 90 degree bends here.
            app.OB_GUI_parameters.R_bend_discharging = 127; %[m] Radius of the bends.
            app.OB_GUI_parameters.r_bend_discharging = 0.019; %[m] Inner radius of the bends.
            app.OB_GUI_parameters.r__bend_connection_discharging = 0.0213;%[m] Inner radius of the entrances and exits of the bends.
            
            %% Ball valve
            app.OB_GUI_parameters.Number_ball_valves_discharging = 2; %[-] Select the number of ball valves here
            app.OB_GUI_parameters.Angle_ball_valve_discharging = 0; %[degree] Select the angle of the ball valves here
            app.OB_GUI_parameters.r_ball_valve_discharging = 0.0213; %[m] Inner radius of the ball valves
            
            %% Union
            app.OB_GUI_parameters.Number_unions_discharging = 8; %[-] Select the number of unions here
            app.OB_GUI_parameters.Union_type_discharging = 1; %[-] Select the union type here, 1 = threaded // 2 = flanged
            app.OB_GUI_parameters.r_union_discharging = 0.0213; %[m] Inner radius of the unions
            
            %% 90-degree elbow
            app.OB_GUI_parameters.Number_90deg_elbows_discharging = 2; %[-] Select the number of 90-degree elbows here
            app.OB_GUI_parameters.r_elbow_90_discharging = 0.0213; %[m] Inner radius of the 90-degree elbows
            
            %% 45-degree elbow
            app.OB_GUI_parameters.Number_45deg_elbows_discharging = 7; %[-] Select the number of 45-degree elbows here
            app.OB_GUI_parameters.r_elbow_45_discharging = 0.0213; %[m] Inner radius of the 45-degree elbows
            
            %% Inlets
            app.OB_GUI_parameters.Number_inlets_discharging = 1; %[-] Select the number of inlets here
            app.OB_GUI_parameters.R_inlet_rounded_discharging = 1; %[m] radius of the rounded inlet bend
            app.OB_GUI_parameters.Inlet_type_discharging = 4; %[-] Select the inlet type here, 1 = square edge // 2 = rounded erge // 3 = extended edge // 4 = chamfered edge
            app.OB_GUI_parameters.r_inlet_discharging = 0.0213; %[m] Inner radius of the pipe right after the inlet 

            %% Contraction
            app.OB_GUI_parameters.r_contraction_1 = 0.0213; %[m] Radius before contraction 
            app.OB_GUI_parameters.r_contraction_2 = 0.0136; %[m] Radius after contraction

            %% New values to table
            if app.table_mode == "charging_parameters"
            app.UITable.RowName=["Number_90deg_bends","R_bend","D_bend","D_bend_connection",...
                "Number_ball_valves","Angle_ball_valve","D_ball_valve","Number_unions",...
                "Union_type","D_union","Number_pressure_sensors","D_pressure_sensor",...
                "Number_flow_meters","Flow_meter_type","D_flow_meter","Number_check_valves",...
                "D_check_valve","Number_90deg_elbows","D_elbow_90","Number_45deg_elbows",...
                "D_elbow_45","Number_inlets","r_inlet_rounded","Inlet_type","D_inlet_discharging"...
                "Number_outlets","D_outlet","Number_bypasses","D_bypass",...
                "Inlet_type_bypass","r_inlet_rounded_bypass",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_charging;app.OB_GUI_parameters.R_bend_charging;app.OB_GUI_parameters.D_bend_charging;app.OB_GUI_parameters.D_bend_connection_charging;...
                app.OB_GUI_parameters.Number_ball_valves_charging;app.OB_GUI_parameters.Angle_ball_valve_charging;app.OB_GUI_parameters.D_ball_valve_charging;app.OB_GUI_parameters.Number_unions_charging;...
                app.OB_GUI_parameters.Union_type_charging;app.OB_GUI_parameters.D_union_charging;app.OB_GUI_parameters.Number_pressure_sensors_charging;app.OB_GUI_parameters.D_pressure_sensor_charging;...
                app.OB_GUI_parameters.Number_flow_meters_charging;app.OB_GUI_parameters.Flow_meter_type_charging;app.OB_GUI_parameters.D_flow_meter_charging;app.OB_GUI_parameters.Number_check_valves_charging;...
                app.OB_GUI_parameters.D_check_valve_charging;app.OB_GUI_parameters.Number_90deg_elbows_charging;app.OB_GUI_parameters.D_elbow_90_charging;app.OB_GUI_parameters.Number_45deg_elbows_charging;...
                app.OB_GUI_parameters.D_elbow_45_charging;app.OB_GUI_parameters.Number_inlets_charging;app.OB_GUI_parameters.r_inlet_rounded_charging;app.OB_GUI_parameters.Inlet_type_charging;app.OB_GUI_parameters.D_inlet_charging;...
                app.OB_GUI_parameters.Number_outlets_charging;app.OB_GUI_parameters.D_outlet_charging;app.OB_GUI_parameters.Number_bypasses_charging;app.OB_GUI_parameters.D_bypass;...
                app.OB_GUI_parameters.Inlet_type_bypass_charging;app.OB_GUI_parameters.r_inlet_rounded_bypass_charging;};


            elseif app.table_mode == "discharging_parameters"
            app.UITable.RowName=["Number_90deg_bends","R_bend","D_bend","D_bend_connection",...
                "Number_ball_valves","Angle_ball_valve","D_ball_valve","Number_unions",...
                "Union_type","D_union","Number_pressure_sensors","D_pressure_sensor",...
                "Number_flow_meters","Flow_meter_type","D_flow_meter","Number_check_valves",...
                "D_check_valve","Number_90deg_elbows","D_elbow_90","Number_45deg_elbows",...
                "D_elbow_45","Number_inlets","r_inlet_rounded","Inlet_type","D_inlet_discharging"...
                "Number_outlets","D_outlet","Number_bypasses","D_bypass",...
                "Inlet_type_bypass","r_inlet_rounded_bypass",];
            app.UITable.Data={app.OB_GUI_parameters.Number_90deg_bends_discharging;app.OB_GUI_parameters.R_bend_discharging;app.OB_GUI_parameters.D_bend_discharging;app.OB_GUI_parameters.D_bend_connection_discharging;...
                app.OB_GUI_parameters.Number_ball_valves_discharging;app.OB_GUI_parameters.Angle_ball_valve_discharging;app.OB_GUI_parameters.D_ball_valve_discharging;app.OB_GUI_parameters.Number_unions_discharging;...
                app.OB_GUI_parameters.Union_type_discharging;app.OB_GUI_parameters.D_union_discharging;app.OB_GUI_parameters.Number_pressure_sensors_discharging;app.OB_GUI_parameters.D_pressure_sensor_discharging;...
                app.OB_GUI_parameters.Number_flow_meters_discharging;app.Flow_meter_type_discharging;app.OB_GUI_parameters.D_flow_meter_discharging;app.OB_GUI_parameters.Number_check_valves_discharging;...
                app.OB_GUI_parameters.D_check_valve_discharging;app.OB_GUI_parameters.Number_90deg_elbows_discharging;app.OB_GUI_parameters.D_elbow_90_discharging;app.OB_GUI_parameters.Number_45deg_elbows_discharging;...
                app.OB_GUI_parameters.D_elbow_45_discharging;app.OB_GUI_parameters.Number_inlets_discharging;app.OB_GUI_parameters.r_inlet_rounded_discharging;app.OB_GUI_parameters.Inlet_type_discharging;app.OB_GUI_parameters.D_inlet_discharging;...
                app.OB_GUI_parameters.Number_outlets_discharging;app.OB_GUI_parameters.D_outlet_discharging;app.OB_GUI_parameters.Number_bypasses_discharging;app.OB_GUI_parameters.D_bypass;...
                app.OB_GUI_parameters.Inlet_type_bypass_discharging;app.OB_GUI_parameters.r_inlet_rounded_bypass_discharging;};
            
            elseif app.table_mode == "main_parameters"
                app.UITable.RowName=["P_electrical", "N_pump", "N_turbine",...
                    "Depth", "Lout", "Lin", "D_rigid", "L_pipeline_discharging",...
                    "D_pipeline", "L_pipeline_charging"];
                app.UITable.Data={app.OB_GUI_parameters.P_electrical; app.OB_GUI_parameters.N_pump; app.OB_GUI_parameters.N_turbine;...
                    app.OB_GUI_parameters.Depth; app.OB_GUI_parameters.Lout; app.OB_GUI_parameters.Lin; app.OB_GUI_parameters.D_rigid; app.OB_GUI_parameters.L_pipeline_discharging;...
                    app.OB_GUI_parameters.D_pipeline; app.OB_GUI_parameters.L_pipeline_charging;};
            end
             
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Image clicked function: ImageDischargingSmall
        function ImageDischargingSmallClicked(app, event)
            
        end

        % Cell edit callback: UITable_K_Values
        function UITable_K_ValuesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Value'};
            app.UITable.RowName = {'Number_90deg_bends'; 'R_bend'; 'D_bend'; 'D_bend_connection'; 'Number_ball_valves'; 'Angle_ball_valve'; 'D_ball_valve'; 'Number_unions'; 'Union_type'; 'D_union'; 'Number_pressure_sensors'; 'D_pressure_sensor'; 'Number_flow_meters'; 'Flow_meter_type'; 'D_flow_meter'; 'Number_check_valves'; 'D_check_valve'; 'Number_90deg_elbows'; 'D_elbow_90'; 'Number_45deg_elbows'; 'D_elbow_45'; 'Number_inlets'; 'r_inlet_rounded'; 'Inlet_type'; 'D_inlet'; 'Number_outlets'; 'D_outlet'; 'Number_bypasses'; 'D_bypass'; 'Inlet_type_bypass'; 'r_inlet_rounded_bypass'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ChargeDischargeDropDown = uidropdown(app.UIFigure);
            app.ChargeDischargeDropDown.Items = {'Main Parameters', 'Charging Parameters', 'Discharging Parameters'};
            app.ChargeDischargeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChargeDischargeDropDownValueChanged, true);
            app.ChargeDischargeDropDown.Value = 'Main Parameters';
            app.EnergySavedkWhEditFieldLabel = uilabel(app.UIFigure);
            app.EnergySavedkWhEditFieldLabel.Text = 'Energy Saved [kWh]';
            app.EnergySavedkWhEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyProducedkWhEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyProducedkWhEditFieldLabel.Text = 'Energy Produced [kWh]';
            app.EnergyProducedkWhEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyoftheSystemEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyoftheSystemEditFieldLabel.Text = 'Efficiency of the System';
            app.EfficiencyoftheSystemEditField = uieditfield(app.UIFigure, 'numeric');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.OverviewTab.ButtonDownFcn = createCallbackFcn(app, @OverviewTabButtonDown, true);
            app.Image_overview = uiimage(app.OverviewTab);
            app.ComputeKValuesButton = uibutton(app.OverviewTab, 'push');
            app.ComputeKValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeKValuesButtonPushed, true);
            app.ComputeKValuesButton.Text = 'Compute K-Values';
            app.UITable_K_Values = uitable(app.OverviewTab);
            app.UITable_K_Values.ColumnName = '';
            app.UITable_K_Values.RowName = {};
            app.UITable_K_Values.CellEditCallback = createCallbackFcn(app, @UITable_K_ValuesCellEdit, true);
            app.UITable_K_Values.Multiselect = 'off';
            app.UITable_K_Values_2 = uitable(app.OverviewTab);
            app.UITable_K_Values_2.ColumnName = '';
            app.UITable_K_Values_2.RowName = {};
            app.UITable_K_Values_2.Multiselect = 'off';
            app.ChargingTab = uitab(app.TabGroup);
            app.ChargingTab.Title = 'Charging';
            app.ChargingTab.ButtonDownFcn = createCallbackFcn(app, @ChargingTabButtonDown, true);
            app.ImageChargingBig = uiimage(app.ChargingTab);
            app.ImageChargingSmall = uiimage(app.ChargingTab);
            app.ImageChargingLegend = uiimage(app.ChargingTab);
            app.SubpartDropDownLabel = uilabel(app.ChargingTab);
            app.SubpartDropDownLabel.Text = 'Sub-part';
            app.SubpartChargingDropDown = uidropdown(app.ChargingTab);
            app.SubpartChargingDropDown.Items = {'Inlet', '90 Degree Bend', 'Pressure Sensor', 'Tee Piece', 'Union', '90 Degree Elbow', '45 Degree Elbow', 'Check Valve', 'Ball Valve', 'Flow Sensor', 'Pump', 'Turbine'};
            app.SubpartChargingDropDown.ValueChangedFcn = createCallbackFcn(app, @SubpartChargingDropDownValueChanged, true);
            app.SubpartChargingDropDown.Value = 'Inlet';
            app.DischargingTab = uitab(app.TabGroup);
            app.DischargingTab.Title = 'Discharging';
            app.DischargingTab.ButtonDownFcn = createCallbackFcn(app, @DischargingTabButtonDown, true);
            app.ImageDischargingBig = uiimage(app.DischargingTab);
            app.ImageDischargingSmall = uiimage(app.DischargingTab);
            app.ImageDischargingSmall.ImageClickedFcn = createCallbackFcn(app, @ImageDischargingSmallClicked, true);
            app.ImageDischargingLegend = uiimage(app.DischargingTab);
            app.SubpartDropDown_2Label = uilabel(app.DischargingTab);
            app.SubpartDropDown_2Label.Text = 'Sub-part';
            app.SubpartDischargingDropDown = uidropdown(app.DischargingTab);
            app.SubpartDischargingDropDown.Items = {'Inlet', '90 Degree Bend', 'Pressure Sensor', 'Tee Piece', 'Union', '90 Degree Elbow', '45 Degree Elbow', 'Check Valve', 'Ball Valve', 'Flow Sensor', 'Pump', 'Turbine'};
            app.SubpartDischargingDropDown.ValueChangedFcn = createCallbackFcn(app, @SubpartDischargingDropDownValueChanged, true);
            app.SubpartDischargingDropDown.Value = 'Inlet';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.InformationMenu = uimenu(app.ContextMenu);
            app.InformationMenu.Text = 'Information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanBattery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end